classdef SlopeStabilityInversionEvaluationSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSIESUIFigure         matlab.ui.Figure
        MenuFile              matlab.ui.container.Menu
        PriorDataImport       matlab.ui.container.Menu
        ObsDataImport         matlab.ui.container.Menu
        ObsErrorImport        matlab.ui.container.Menu
        ExcaChaImport         matlab.ui.container.Menu
        SaveProj              matlab.ui.container.Menu
        OpenProj              matlab.ui.container.Menu
        MenuSurrModel         matlab.ui.container.Menu
        ModelInputIm          matlab.ui.container.Menu
        ModelOutputIm         matlab.ui.container.Menu
        ModelTrain            matlab.ui.container.Menu
        GPRMenu               matlab.ui.container.Menu
        BPNNMenu              matlab.ui.container.Menu
        MARSMenu              matlab.ui.container.Menu
        SaveModel             matlab.ui.container.Menu
        ImportModel           matlab.ui.container.Menu
        PabInverMenu          matlab.ui.container.Menu
        BeginInver            matlab.ui.container.Menu
        MHMenu                matlab.ui.container.Menu
        DREAMMenu             matlab.ui.container.Menu
        SaveInverMenu         matlab.ui.container.Menu
        SlopeCondiMenu        matlab.ui.container.Menu
        PrediMenu             matlab.ui.container.Menu
        SavePreMenu           matlab.ui.container.Menu
        SimpleSlopeMenu       matlab.ui.container.Menu
        FellenMenu            matlab.ui.container.Menu
        ModComMenu            matlab.ui.container.Menu
        ComplexMenu           matlab.ui.container.Menu
        ModelCompuMenu        matlab.ui.container.Menu
        SaveSafety            matlab.ui.container.Menu
        StopMenu              matlab.ui.container.Menu
        CancelNow             matlab.ui.container.Menu
        QuitApp               matlab.ui.container.Menu
        TabTableData          matlab.ui.container.TabGroup
        TabInverData          matlab.ui.container.Tab
        ExcaChaTable          matlab.ui.control.Table
        Label_5               matlab.ui.control.Label
        ObsErrorTable         matlab.ui.control.Table
        Label_4               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        ObsDataTable          matlab.ui.control.Table
        Label                 matlab.ui.control.Label
        PriorDataTable        matlab.ui.control.Table
        TabModelVisual        matlab.ui.container.Tab
        PaintModel            matlab.ui.container.Panel
        MaxErrButton          matlab.ui.control.Button
        DimRMSEButton         matlab.ui.control.Button
        DimR2                 matlab.ui.control.Button
        MDimPre               matlab.ui.control.Button
        MoAccuTe              matlab.ui.control.TextArea
        ProgreTe              matlab.ui.control.TextArea
        MTLabelRight          matlab.ui.control.Label
        MTLabelLeft           matlab.ui.control.Label
        OutputTable           matlab.ui.control.Table
        InputTable            matlab.ui.control.Table
        UIAxesModel           matlab.ui.control.UIAxes
        TabParaInversion      matlab.ui.container.Tab
        Panel_2               matlab.ui.container.Panel
        allPDFButton          matlab.ui.control.Button
        allCOVButton          matlab.ui.control.Button
        InverPaintPanel       matlab.ui.container.Panel
        COVDiaButton          matlab.ui.control.Button
        PDFDiaButton          matlab.ui.control.Button
        SamplingDiaButton     matlab.ui.control.Button
        ExcaEditField         matlab.ui.control.EditField
        Label_9               matlab.ui.control.Label
        PaintParaDropDown     matlab.ui.control.DropDown
        Label_8               matlab.ui.control.Label
        InverTextArea         matlab.ui.control.TextArea
        Panel                 matlab.ui.container.Panel
        DistributionDropDown  matlab.ui.control.DropDown
        DropDown_3Label       matlab.ui.control.Label
        ModelDropDown         matlab.ui.control.DropDown
        Label_7               matlab.ui.control.Label
        ChoseTableDropDown    matlab.ui.control.DropDown
        Label_6               matlab.ui.control.Label
        PosterLabel           matlab.ui.control.Label
        PosterParaTable       matlab.ui.control.Table
        PosterParaUIAxes      matlab.ui.control.UIAxes
        SlopeTab              matlab.ui.container.Tab
        Panel_3               matlab.ui.container.Panel
        SlopeSafetyButton     matlab.ui.control.Button
        SlopeSafeLabel        matlab.ui.control.Label
        SlopeSafeLamp         matlab.ui.control.Lamp
        Label_14              matlab.ui.control.Label
        SlopeCondiDropDown    matlab.ui.control.DropDown
        Label_13              matlab.ui.control.Label
        SlopeGradeDropDown    matlab.ui.control.DropDown
        Label_12              matlab.ui.control.Label
        DisplaDiaPanel        matlab.ui.container.Panel
        AllDisButton          matlab.ui.control.Button
        OneDisButton          matlab.ui.control.Button
        HoldonCheckBox        matlab.ui.control.CheckBox
        PreDisDropDown        matlab.ui.control.DropDown
        Label_11              matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Label_10              matlab.ui.control.Label
        PreLabel              matlab.ui.control.Label
        SlopeTable            matlab.ui.control.Table
        SlopeUIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = public)
        InputData    % 训练模型所用输入参数数据
        OutputData   % 训练模型所用输出参数数据
        InParaDim    % 模型输入参数维度
        OutParaDim   % 模型输出参数维度
        InputNorm    % 模型输入归一化
        OutputNorm   % 模型输出归一化
        Models       % 代理模型
        OutTest      % 测试集
        OutTPre      % 测试集预测值
        ObsName      % 输出维度名字
        GeoPara      % 岩土参数类别
        PriorValue   % 岩土参数先验值（第一行均值，第二行标准差）
        ObsData      % 观测数据
        ObsError     % 观测误差（第一行均值，第二行标准差）
        ExcaChara    % 开挖特征
        ResultPara   % 参数后验均值，第一行为先验
        ResultStd    % 参数后验标准差，第一行为先验
        ResultCell   % 抽样元胞数组，第一个维度指定反演阶段，第二个维度指定参数，第三个维度指定第几次抽样（一般忽略）
        PredictDis   % 预测位移
        SlopeFoS     % 边坡安全系数
        RgbColor = [0.57, 0.69, 0.30; 0.89, 0.88, 0.57; 0.76, 0.49, 0.58; 0.47, 0.76, 0.81; 0.21, 0.21, 0.35; 0.28, 0.57, 0.54; 0.07, 0.35, 0.40; 0.41, 0.20, 0.42; 0.60, 0.24, 0.18; 0.76, 0.84, 0.65];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

        Property14 % Description
        Property25 % Description
    end


    properties (Access = private)
        ForceExit = false; % 强制退出标志
        TimerObj;        % 定时器对象
        LogFile;         % 临时日志文件路径
        LastPosition = 0;% 文件最后读取位置
    end


    methods (Access = public)

        function data = ImportFunc(app) %选择文件，获取文件路径，然后导入数据
            % 创建支持多格式的文件过滤器
            filetypes = {
                '*.xls;*.xlsb;*.xlsm;*.xlsx;*.xltm;*.xltx', 'Excel文件 (*.xls,*.xlsb,*.xlsm,*.xlsx)';
                '*.txt;*.dat', '文本文件 (*.txt, *.dat)';
                '*.csv', 'CSV文件 (*.csv)';
                '*.*',  '所有文件 (*.*)'
                };

            % 弹出文件选择对话框[1,3](@ref)
            [filename, pathname] = uigetfile(filetypes, '选择观测数据误差文件');

            if isequal(filename,0) || isequal(pathname,0)
                return; % 用户取消选择时退出[3](@ref)
            end

            try
                fullpath = fullfile(pathname, filename);

                % 根据扩展名选择读取方式[6,7](@ref)
                [~, ~, ext] = fileparts(fullpath);

                % 统一读取配置
                opts = detectImportOptions(fullpath);
                opts.PreserveVariableNames = true;  % 关闭自动重命名

                % 执行数据读取[7,8](@ref)
                if any(strcmpi(ext, {'.xls', '.xlsb', '.xlsm', '.xlsx', '.xltm', '.xltx'}))
                    data = readtable(fullpath, opts, 'UseExcel', false); % Excel专用参数
                else
                    data = readtable(fullpath, opts); % 通用文本读取
                end

            catch ME
                % 错误处理[3,4](@ref)
                errordlg(sprintf('文件读取失败:\n%s', ME.message), '读取错误');
            end
        end


        function ModelPaintUI(app)
            cla(app.UIAxesModel);
            reset(app.UIAxesModel);
            % 在坐标区绘图
            xiao=floor(min(app.OutTest,[],'all'));da=ceil(max(app.OutTest,[],'all'));
            plot(app.UIAxesModel,[xiao da], [xiao da], 'k-', 'LineWidth', 1.2,'HandleVisibility', 'off');
            xlim(app.UIAxesModel,[xiao da]);
            ylim(app.UIAxesModel,[xiao da]);
            for i=1:app.OutParaDim
                Y_test=app.OutTest(:,i);
                Y_pred1=app.OutTPre(:,i);
                hold(app.UIAxesModel, 'on');
                scatter(app.UIAxesModel,Y_test(:), Y_pred1(:), 50,'filled','s','MarkerFaceColor', app.RgbColor(i,:),'DisplayName',string(app.ObsName(i)));
                %text(xiao+0.1*(da-xiao), da-0.1*(da-xiao),app.(i),'Color', rgbTriplet(i,:), 'FontSize',12, 'FontWeight','bold');
                %set(gca, 'FontSize',12);

            end
            app.UIAxesModel.XGrid = 'off';
            app.UIAxesModel.YGrid = 'off';
            app.UIAxesModel.Box = 'on';
            pbaspect(app.UIAxesModel, [1 1 1]);
            if size(app.OutTest,2) <= 16
                legend(app.UIAxesModel, 'Location', 'northeastoutside');
            end
            % 添加坐标标签
            xlabel(app.UIAxesModel, '数值模拟计算值(mm)');
            ylabel(app.UIAxesModel, '模型预测值(mm)');
            title(app.UIAxesModel, '训练结果');
        end

        function results = MaxSign(app,A)  %#ok<INUSL> % 返回带符号的最大值
            [~, idx] = max(abs(A));  % 找到每列最大绝对值的索引（行号）
            results = A(sub2ind(size(A), idx, 1:size(A,2)));  % 提取原始值（保留正负）
        end

        function WritePre(app,Y_test_raw,Y_pred)  % 评价精度并输出到多行文本控件
            err=Y_pred-Y_test_raw;
            RMSEDim=sqrt(mean((err).^2, 1));
            RMSE=sqrt(mean((err).^2, 'all'));
            ss_total = sum((Y_test_raw - mean(Y_test_raw)).^2, 1);
            ss_residual = sum((Y_test_raw - Y_pred).^2, 1);
            r2_per_dim = 1 - ss_residual./ss_total;
            r2=mean(r2_per_dim);
            maxer=MaxSign(app,err);

            %显示到界面表格控件
            app.MTLabelLeft.Text='各维度预测精度';
            app.MTLabelRight.Text='第1、2、3行分别为R²、RMSE(mm)、最大误差(mm)';
            Inde=floor(app.OutParaDim/2);
            app.InputTable.ColumnName=app.ObsName(1:Inde);
            app.InputTable.Data=[r2_per_dim(1:Inde);RMSEDim(1:Inde);maxer(1:Inde)];
            temp=app.ObsName(Inde+1:end);
            app.OutputTable.ColumnName=temp;
            app.OutputTable.Data=[r2_per_dim(Inde+1:end);RMSEDim(Inde+1:end);maxer(Inde+1:end)];
            drawnow;

            %显示总体精度（修改为sprintf格式）
            app.MoAccuTe.Value = sprintf(['模型总体精度：\n'...
                '决定系数R²：%.4f;'...
                'RMSE：%.3f (mm);\n'...
                '最大误差：%.3f (mm)'], ...
                round(r2,4), round(RMSE,3), round(MaxSign(app,maxer'),3));
            drawnow;
        end

        function [params, expectation_param, std_param] = MHupdate(app, geoData, obsData, inputW, exca, columns_to_extract)
            % 步骤1：初始化进度条
            dlg = uiprogressdlg(app.SSIESUIFigure, 'Title', 'MCMC 采样中', ...
                'Message', '正在初始化...', ...
                'Cancelable','on',...
                'Indeterminate', 'on'); % 初始为不确定进度
            drawnow; % 立即更新界面

            try
                % 步骤2：观测数据
                obsData_value = obsData(:, 1); % 观测数据
                obsData_std = obsData(:, 2);  % 观测数据的先验标准差

                % 步骤3：非确定性向量的先验信息
                prior_mean = geoData(:, 1);   % 先验分布的均值
                prior_std = geoData(:, 2);    % 先验分布的标准差
                [m, ~] = size(prior_mean);    % m 为非确定性参数的数量
                [n_obs, ~] = size(obsData);   % n_obs 为观测量的数量
                covAdv = eye(m, m);
                for i = 1:m
                    covAdv(i, i) = prior_std(i)^2;
                end

                % 计算不确定参数向量先验信息的对数正态分布参数
                mu0 = zeros(m, 1);
                sigma0 = zeros(m, 1);
                for i = 1:m
                    [mu0(i), sigma0(i)] = OcalDisP(app, prior_mean(i), prior_std(i));
                end

                % 步骤4：MCMC 参数设置
                n_iterations = 100000;        % MCMC 迭代次数
                params = zeros(m, n_iterations); % 存储待估参数
                params(:, 1) = prior_mean;    % 初始化参数
                sfactor = 2.38 * 2.38 / m;   % 缩放因子

                % 更新进度条为确定性模式
                dlg.Message = 'MCMC 采样进行中...';
                dlg.Indeterminate = 'off';
                dlg.Value = 0;
                drawnow;

                % 步骤5：MCMC 迭代
                for i = 2:n_iterations
                    % 更新进度条
                    if mod(i, 1000) == 0
                        dlg.Value = i / n_iterations;
                        dlg.Message = sprintf('第%d阶段反演已完成 %.1f%%', ...
                            exca, i/ n_iterations * 100);
                        drawnow;
                    end

                    % 从建议分布中采样新样本
                    muAdv = params(:, i-1);
                    proposal = mvnrnd(muAdv, 0.5 * sfactor * covAdv)';

                    % 代理模型预测
                    inputW(:, 1:m) = proposal;
                    preD1 = SurrogateModel(app, inputW);
                    preD1 = (preD1(:, columns_to_extract))';

                    % 计算似然函数
                    likelihood = OlikehoodCal(app, obsData_value, obsData_std, preD1);
                    paramTemp = params(:, i-1);

                    % 计算上一状态的似然函数
                    inputTemp = inputW;
                    inputTemp(:, 1:m) = paramTemp';
                    preDTemp = SurrogateModel(app, inputTemp);
                    preDTemp = (preDTemp(:, columns_to_extract))';
                    likelihood0 = OlikehoodCal(app, obsData_value, obsData_std, preDTemp); %#ok<ADMTHDINV>

                    % 根据分布类型计算先验概率
                    switch app.DistributionDropDown.Value
                        case '对数正态分布'
                            prior = zeros(m, 1);
                            prior0 = zeros(m, 1);
                            for j = 1:m
                                prior(j) = lognpdf(proposal(j), mu0(j), sigma0(j));
                                prior0(j) = lognpdf(paramTemp(j), mu0(j), sigma0(j));
                            end
                        case '正态分布'
                            prior = zeros(m, 1);
                            prior0 = zeros(m, 1);
                            for j = 1:m
                                prior(j) = normpdf(proposal(j), mu0(j), sigma0(j));
                                prior0(j) = normpdf(paramTemp(j), mu0(j), sigma0(j));
                            end
                    end

                    % 计算接受比率
                    priorpdf = prod(prior);
                    priorpdf0 = prod(prior0);
                    acceptance_ratio = priorpdf * likelihood / (priorpdf0 * likelihood0);

                    % 接受或拒绝建议的参数
                    if rand < acceptance_ratio
                        params(:, i) = proposal;
                    else
                        params(:, i) = params(:, i-1);
                    end
                    if dlg.CancelRequested
                        break
                    end
                end

                % 步骤6：计算后验统计量（去掉燃烧期前10%）
                burn_in = round(0.1 * n_iterations);
                expectation_param = mean(params(:, burn_in+1:end), 2);
                std_param = std(params(:, burn_in+1:end), 0, 2);
                params = params(:, burn_in+1:end);

                % 关闭进度条
                dlg.Message = 'MCMC 采样完成！';
                dlg.Value = 1;
                pause(1);

            catch ME
                % 错误处理：关闭进度条并重新抛出错误
                close(dlg);
                rethrow(ME);
            end

            % 确保进度条关闭
            close(dlg);
        end

        % 计算对数正态分布均值和标准差
        function [mu,sigma] = OcalDisP(app, m, v) %#ok<*ADAPPREF>
            switch app.DistributionDropDown.Value
                case '对数正态分布'
                    mu = log(m.^2 ./ sqrt(v.^2 + m.^2));
                    sigma = sqrt(log((v.^2)./(m.^2)+1));
                case '正态分布'
                    mu=m;
                    sigma=v;
            end
        end

        % 计算似然函数
        function likeH = OlikehoodCal(app, obsValue,obsStd,preData) %#ok<INUSL>
            [row,~]=size(obsValue);
            likeH=1;
            for i=1:row
                likeH=likeH*(1/(sqrt(2*pi)*obsStd(i)))* exp((-1/2)*((obsValue(i)-preData(i))/obsStd(i))^2);
                %TEMP=(obsData(i)-simData(i))/obsData_std(i)^2;
            end
        end

        % 调用代理模型计算位移
        function [outputy] = SurrogateModel(app, inputx)
            % 输入1*(参数数量+开挖特征)行向量，输出1*（测站数）行向量
            % 判断后使用模型进行预测

            switch app.ModelDropDown.Value
                case 'GPR'
                    rl=zeros(app.OutParaDim,1);
                    input=mapminmax('apply',inputx',app.InputNorm)';
                    for n=1:16
                        rl(n)=predict(app.Models{n}, input);
                    end
                    outputy=mapminmax('reverse',rl,app.OutputNorm)';
                case 'BPNN'
                    input=mapminmax('apply',inputx',app.InputNorm);
                    rl=app.Models(input);
                    outputy=mapminmax('reverse',rl,app.OutputNorm)';
                case 'MARS'
                    input=mapminmax('apply',inputx',app.InputNorm)';
                    rh = arespredict(app.Models, input);
                    outputy=mapminmax('reverse',rh',app.OutputNorm)';
            end
        end

        %DREAM算法更新
        function [params, expectation_param, std_param] = DREAMupdate(app, geoData, obsData, inputW, exca, columns_to_extract)

            global obsData_value obsData_std inputW_global globalModelParams;
            obsData_value = obsData(:, 1); % 观测数据
            obsData_std = obsData(:, 2);   % 观测数据的先验标准差
            inputW_global = inputW;        % 输入代理模型的数据（全局变量），行向量
            globalModelParams.modelType = app.ModelDropDown.Value;
            globalModelParams.models = app.Models;
            globalModelParams.inputNorm = app.InputNorm;
            globalModelParams.outputNorm = app.OutputNorm;
            globalModelParams.outParaDim = app.OutParaDim;
            % 非确定性向量的先验信息
            prior_mean = geoData(:, 1); % 先验分布的均值
            prior_std = geoData(:, 2);  % 先验分布的标准差
            [m, ~] = size(prior_mean);  % m为非确定性参数的数量

            %计算不确定参数向量先验信息的-对数正态分布的参数
            mu0=zeros(m,1);sigma0=zeros(m,1);
            for i=1:m
                [mu0(i),sigma0(i)]=OcalDisP(app, prior_mean(i),prior_std(i));
            end

            % 定义DREAM算法的参数
            DREAMPar.d = m;                         % 参数空间的维度
            DREAMPar.lik = 2;                       % 模型输出是似然值（当取值为2时返回对数似然值）
            DREAMPar.N = 5;                         % 马尔可夫链的数量
            DREAMPar.T = 20000;                     % 迭代次数

            % 定义参数空间、初始采样和先验分布
            Par_info.initial = 'normal';            % 初始采样分布
            Par_info.mu = prior_mean';              % 先验均值
            Par_info.cov = diag(prior_std.^2);      % 先验协方差矩阵
            % 参数先验分布，此方法传入参数必须是字符数组，且数组内均值和标准差不能用变量表示，所以需做个转换。
            %该方法只能表示变量互相独立分布
            switch app.DistributionDropDown.Value
                case '对数正态分布'
                    pri=cell(1,m);
                    for a=1:m
                        temp = ['lognpdf(', num2str(mu0(a)), ',', num2str(sigma0(a)), ')'];
                        pri{1,a}=temp;
                    end
                    Par_info.prior = pri;
                case '正态分布'
                    pri=cell(1,m);
                    for a=1:m
                        temp = ['normpdf(', num2str(mu0(a)), ',', num2str(sigma0(a)), ')'];
                        temp=char(temp);
                        pri(1,a)=temp;
                    end
                    Par_info.prior = pri;
            end

            %参数先验分布，由于没有现成的多元对数正态分布，所以在多参数情况下下面的定义参数方法很麻烦（需自己定义一个函数）
            %Par_info.prior = @(x,a,b)mvnpdf(x,a,b);
            %Par_info.a = mu0';                   % Mean of prior distribution
            %Par_info.b = diag(sigma0');                    % Covariance of prior distribution

            Par_info.min = prior_mean'-3*prior_std';
            Par_info.max = prior_mean'+3*prior_std';
            Par_info.boundhandling='fold';

            % 定义似然函数
            Func_name = 'displacement';  % 自定义的似然函数

            %选择DREAM算法
            method = 'dream_zs'; %_zs'; %_dzs';
            switch method
                case {'dream','dream_d'}
                    DREAMPar.N = 10;                            % 马尔可夫链的数量
                    DREAMPar.T = 10000;                         % 迭代次数
                case {'dream_zs','dream_dzs'}
                    DREAMPar.N = 5;                             % 马尔可夫链的数量
                    DREAMPar.T = 20000;                         % 迭代次数
            end

            if strcmp(method,'dream_d') || strcmp(method,'dream_dzs')
                Par_info.min = [ -100 -100 ];                   % Min value for discrete sampling
                Par_info.max = [  100  100 ];                   % Max value for discrete sampling
                Par_info.steps = 1500*ones(1,DREAMPar.d);       % Number of discrete steps
            end


            % 保存原始关闭回调函数
            originalCloseFcn = app.SSIESUIFigure.CloseRequestFcn;

            % 设置为空函数，阻止关闭操作
            app.SSIESUIFigure.CloseRequestFcn = @(src, event) [];
            options.print = 'no';
            %options.parallel='yes';
            % 4. 运行DREAM算法
            [chain, output, FX] = DREAM_package(method, Func_name, DREAMPar, Par_info, [],options);
            % 恢复原始关闭回调函数
            app.SSIESUIFigure.CloseRequestFcn = originalCloseFcn;


            % 提取后验分布的参数
            % 定义燃烧期比例，例如 20%（也可以使用返回矩阵output中的.R_stat和.MR_stat，即单变量/多变量收敛诊断统计量，值接近 1，则表示链已经收敛）
            burnin_ratio = 0.1;
            burnin_length = floor(30000 * burnin_ratio);
            % 去除燃烧期的样本,提取 2 个未知参数，跳过对数先验和对数似然
            % 合并各个参数所有链的样本
            for i=1:m
                after_burnin = chain(burnin_length + 1:end, i, :);
                samples = reshape(after_burnin, 1, []);
                params(i,:) = samples;
            end
            % 计算参数的期望值
            expectation_param = mean(params,2);
            % 计算参数的标准差
            std_param = std(params,0,2);
        end
    end


    methods (Access = private)

        function cleanupResources(app)  % 清理函数
            % 关闭所有文件
            fclose('all');

            restoredefaultpath;
            % 释放串口
            if isprop(app, 'serialPort') && isvalid(app.serialPort)
                fclose(app.serialPort);
                delete(app.serialPort);
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appRoot = fileparts(mfilename('fullpath'));
            folder = fullfile(appRoot,'ARESLab');     % 获取当前文件夹的绝对路径
            % 检查是否有保存的数据
            if isfile('last_run_data.mat')
                answer = uiconfirm(app.SSIESUIFigure, ...
                    '检测到上次保存的数据，是否加载？', ...
                    '加载数据', ...
                    'Options', {'加载', '跳过'}, ...
                    'DefaultOption', 1);

                if strcmp(answer, '加载')
                    try
                        % 从 MAT 文件加载数据
                        savedData = load('last_run_data.mat');

                        % 将数据恢复到 app 属性
                        app.InputData    = savedData.InputData;
                        app.OutputData   = savedData.OutputData;
                        app.InParaDim    = savedData.InParaDim;
                        app.OutParaDim   = savedData.OutParaDim;
                        app.InputNorm    = savedData.InputNorm;
                        app.OutputNorm   = savedData.OutputNorm;
                        app.Models       = savedData.Models;
                        app.OutTest      = savedData.OutTest;
                        app.OutTPre      = savedData.OutTPre;
                        app.ObsName      = savedData.ObsName;
                        app.GeoPara      = savedData.GeoPara;
                        app.PriorValue   = savedData.PriorValue;
                        app.ObsData      = savedData.ObsData;
                        app.ObsError     = savedData.ObsError;
                        app.ExcaChara    = savedData.ExcaChara;
                        app.ResultPara   = savedData.ResultPara;
                        app.ResultStd    = savedData.ResultStd;
                        app.ResultCell   = savedData.ResultCell;

                        % 更新 UI 显示
                        % 弹窗显示加载成功信息（带时间戳）
                        uialert(app.SSIESUIFigure, ...
                            sprintf('数据已加载（保存时间: %s）', ...
                            datestr(savedData.timestamp, 'yyyy-mm-dd HH:MM:SS')), ...
                            '加载成功', ...
                            'Icon', 'success');
                        app.PaintParaDropDown.Items=app.GeoPara;
                        app.PreDisDropDown.Items=app.ObsName;

                    catch ME
                        uialert(app.SSIESUIFigure, ...
                            sprintf('加载数据失败: %s', ME.message), ...
                            '错误');
                    end
                end
            end
        end

        % Menu selected function: PriorDataImport
        function PriorDataImportMenuSelected(app, event)
            data=ImportFunc(app);

            % 更新表格控件[3,5](@ref)
            app.PriorDataTable.Data = data;
            app.PriorDataTable.ColumnName = data.Properties.VariableNames;
            app.GeoPara = app.PriorDataTable.ColumnName;
            app.PriorValue = table2array(data);
        end

        % Menu selected function: ObsDataImport
        function ObsDataImportMenuSelected(app, event)
            data=ImportFunc(app);

            % 更新表格控件[3,5](@ref)
            app.ObsDataTable.Data = data;
            app.ObsDataTable.ColumnName = data.Properties.VariableNames;

            app.ObsData = table2array(data);
        end

        % Menu selected function: ObsErrorImport
        function ObsErrorImportMenuSelected(app, event)
            data=ImportFunc(app);

            % 更新表格控件[3,5](@ref)
            app.ObsErrorTable.Data = data;
            app.ObsErrorTable.ColumnName = data.Properties.VariableNames;

            app.ObsError = table2array(data);
        end

        % Menu selected function: ExcaChaImport
        function ExcaChaImportSelected(app, event)
            data=ImportFunc(app);

            if size(data,1)>=size(app.ObsDataTable.Data,1)
                % 更新表格控件[3,5](@ref)
                app.ExcaChaTable.Data = data;
                app.ExcaChaTable.ColumnName = data.Properties.VariableNames;

                app.ExcaChara=table2array(data);
            else
                errordlg('开挖特征时期数小于观测数据时期数', '读取错误');
            end
        end

        % Menu selected function: ModelInputIm
        function ModelInputImMenuSelected(app, event)
            data=ImportFunc(app);

            app.InputTable.Data = data;
            app.InputTable.ColumnName = data.Properties.VariableNames;

            app.InputData=table2array(data);
            app.InParaDim=size(app.InputData, 2);

            app.TabTableData.SelectedTab=app.TabModelVisual;
            figure(app.SSIESUIFigure);
        end

        % Menu selected function: ModelOutputIm
        function ModelOutputImMenuSelected(app, event)
            data=ImportFunc(app);

            if size(data,1)==size(app.InputTable.Data,1)
                % 更新表格控件[3,5](@ref)
                app.OutputTable.Data = data;
                app.OutputTable.ColumnName = data.Properties.VariableNames;
            else
                errordlg('输入与输出样本数不等', '读取错误');
            end

            app.OutputData=table2array(data);
            app.OutParaDim=size(app.OutputData, 2);
            app.ObsName=app.OutputTable.ColumnName;
        end

        % Menu selected function: GPRMenu
        function GPRMenuSelected(app, event)
            app.ProgreTe.Value = sprintf('程序准备中...');

            if isempty(app.InputData) || isempty(app.OutputData)
                % 在App界面显示错误提示
                uialert(app.SSIESUIFigure, '请先加载输入/输出数据！', '数据缺失', 'Icon', 'error');
                return; % 直接退出当前函数
            end
            % 步骤1：导入训练数据
            input_data=app.InputData;
            output_data=app.OutputData;
            sta=tic;

            % 步骤2：划分训练集和测试集（80-20比例）
            rng(10);
            SampleNum = size(input_data,1); % 样本数
            IPD = app.InParaDim; % 输入参数维度
            OPD=app.OutParaDim; % 输出参数维度
            test_ratio = 0.2;
            train_ratio = 1 - test_ratio;

            idx = randperm(SampleNum);
            train_idx = idx(1:round(SampleNum*train_ratio));
            test_idx = idx(round(SampleNum*train_ratio)+1:end);

            X_train_raw = input_data(train_idx, :);  % 训练集原始数据（未归一化）
            Y_train_raw = output_data(train_idx, :);
            X_test_raw = input_data(test_idx, :);     % 测试集原始数据（保持未处理）
            Y_test_raw = output_data(test_idx, :);

            % 数据集归一化
            [X_train_norm, InNorm] = mapminmax(X_train_raw', 0, 1);  % 仅用训练集计算归一化
            X_train_norm = X_train_norm';
            X_test_norm = mapminmax('apply', X_test_raw', InNorm)';
            [Y_train_norm, OutNorm] = mapminmax(Y_train_raw', 0, 1);
            Y_train_norm = Y_train_norm';


            %% 步骤3：运行PSO优化（五折交叉验证）
            lb = log([1e-3, 1e-3, 1e-3*ones(1,IPD)]);  % 参数下限
            ub = log([1e3, 1e3, 1e3*ones(1,IPD)]);     % 参数上限

            options = optimoptions('particleswarm',...
                'SwarmSize', 30,...
                'MaxIterations', 40,...
                'Display', 'iter',...
                'UseParallel', true);

            app.ProgreTe.Value = sprintf('模型训练开始！\n训练进度：0%%');  % 修改为sprintf格式
            drawnow;

            models = cell(OPD,1);
            for i = 1:OPD  % 并行优化每个输出维度
                % 运行PSO优化（五折交叉验证）
                best_params = particleswarm(...
                    @(params) gpr_fitness_cv(params, X_train_norm, Y_train_norm(:,i)),...
                    length(lb), lb, ub, options);

                % 转换参数
                sigma = exp(best_params(1));
                sigma_obs = exp(best_params(2));
                scales = exp(best_params(3:end))';
                kp = [scales; sigma];

                % 训练最终模型
                models{i} = fitrgp(X_train_norm, Y_train_norm(:,i),...
                    'KernelFunction', 'ardsquaredexponential',...
                    'KernelParameters', kp,...
                    'Sigma', sigma_obs,...
                    'Standardize', false);

                app.ProgreTe.Value = sprintf('模型训练开始！\n训练进度：%.1f%%', i/OPD*100);  % 修改为sprintf格式
                drawnow;
            end
            ela=toc(sta);
            app.ProgreTe.Value = sprintf('模型训练结束！\n训练花费时间：%d s', round(ela));  % 修改为sprintf格式

            %% 步骤4：测试集归一化与预测
            Y_pred_norm = zeros(size(Y_test_raw));
            for i = 1:OPD
                Y_pred_norm(:,i) = predict(models{i}, X_test_norm);
            end
            Y_pred = mapminmax('reverse', Y_pred_norm', OutNorm)';
            %保存模型、归一化参数、测试集
            app.Models=models;
            app.InputNorm=InNorm;
            app.OutputNorm=OutNorm;
            app.OutTest=Y_test_raw;
            app.OutTPre=Y_pred;

            %性能评估并显示（原始量纲）
            WritePre(app,Y_test_raw,Y_pred);

            % 在坐标区绘图
            ModelPaintUI(app);
            drawnow;

            %% ------------------ 五折交叉验证适应度函数 ------------------ %%
            function fitness = gpr_fitness_cv(params, X, Y)
                k = 5;
                cv = cvpartition(size(X,1), 'KFold', k);
                mse_scores = zeros(k,1);

                for fold = 1:k
                    % 划分训练/验证集（原始数据）
                    trainIdx = training(cv, fold);
                    valIdx = test(cv, fold);

                    % 转换参数
                    sig = exp(params(1));
                    sig_obs = exp(params(2));
                    scal = exp(params(3:end))';
                    KP = [scal; sig];

                    try
                        % 训练模型
                        model = fitrgp(X(trainIdx,:), Y(trainIdx,:),...
                            'KernelFunction', 'ardsquaredexponential',...
                            'KernelParameters', KP,...
                            'Sigma', sig_obs,...
                            'Standardize', false);

                        % 预测验证集
                        YPred = predict(model, X(valIdx,:));
                        % 计算MSE（原始量纲）
                        mse_scores(fold) = mean((Y(valIdx,:) - YPred).^2);
                    catch
                        mse_scores(fold) = 1e6;  % 无效参数惩罚
                    end
                end

                fitness = mean(mse_scores);
            end
        end

        % Menu selected function: CancelNow
        function CancelNowMenuSelected(app, event)
            % 1. 询问用户是否保存数据
            answer = uiconfirm(app.SSIESUIFigure, ...
                '是否保存当前所有数据以便下次使用？', ...
                '确认退出', ...
                'Options', {'保存并退出', '直接退出', '取消'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);

            % 2. 根据用户选择执行操作
            switch answer
                case '保存并退出'
                    % 将所有数据保存到 app.savedData 结构体
                    savedData = struct();
                    savedData.InputData    = app.InputData;       % 允许为空
                    savedData.OutputData   = app.OutputData;      % 允许为空
                    savedData.InParaDim    = app.InParaDim;       % 允许为空
                    savedData.OutParaDim   = app.OutParaDim;      % 允许为空
                    savedData.InputNorm    = app.InputNorm;       % 允许为空
                    savedData.OutputNorm   = app.OutputNorm;      % 允许为空
                    savedData.Models       = app.Models;          % 允许为空
                    savedData.OutTest      = app.OutTest;         % 允许为空
                    savedData.OutTPre      = app.OutTPre;         % 允许为空
                    savedData.ObsName      = app.ObsName;         % 允许为空
                    savedData.GeoPara      = app.GeoPara;         % 允许为空
                    savedData.PriorValue   = app.PriorValue;      % 允许为空
                    savedData.ObsData      = app.ObsData;         % 允许为空
                    savedData.ObsError     = app.ObsError;        % 允许为空
                    savedData.ExcaChara    = app.ExcaChara;       % 允许为空
                    savedData.ResultPara   = app.ResultPara;      % 允许为空
                    savedData.ResultStd    = app.ResultStd;       % 允许为空
                    savedData.ResultCell   = app.ResultCell;      % 允许为空
                    savedData.timestamp    = datetime('now');     % 非空

                    % 保存到 MAT 文件（持久化存储）
                    save('last_run_data.mat', '-struct', 'savedData');

                    % 关闭 App
                    app.cleanupResources();
                    delete(app.SSIESUIFigure);

                case '直接退出'
                    % 不保存数据，直接关闭
                    app.cleanupResources();
                    delete(app.SSIESUIFigure);

                case '取消'
                    % 什么都不做，继续运行
                    return;
            end
        end

        % Menu selected function: QuitApp
        function QuitAppMenuSelected(app, event)
            % 弹出选择对话框
            selection = uiconfirm(app.SSIESUIFigure,...
                '请选择退出方式:',...
                '退出程序',...
                'Icon','warning',...
                'Options',{'仅关闭窗口','完全退出MATLAB','取消'});

            switch selection
                case '仅关闭窗口'
                    app.cleanupResources(); % 自定义清理方法
                    delete(app.SSIESUIFigure);

                case '完全退出MATLAB'
                    app.ForceExit = true;
                    app.cleanupResources();
                    if ~isempty(gcp('nocreate'))
                        delete(gcp('nocreate')); % 关闭并行池
                    end
                    quit force;
            end
        end

        % Menu selected function: SaveModel
        function SaveModelMenuSelected(app, event)
            % 弹出文件保存对话框
            [filename, pathname] = uiputfile(...
                {'*.mat', 'MAT-files (*.mat)'}, ...
                '保存代理模型参数', ...
                'MyModelParameters.mat'); % 默认文件名

            % 检查用户是否取消保存
            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.UIFigure, '保存已取消', '操作取消');
                return;
            end

            % 构建完整文件路径
            fullpath = fullfile(pathname, filename);

            % 创建结构体保存所有参数
            modelData = struct();

            % 保存所有指定参数
            try
                modelData.InParaDim = app.InParaDim;    % 输入参数维度
                modelData.OutParaDim = app.OutParaDim; % 输出参数维度
                modelData.InputNorm = app.InputNorm;    % 输入归一化参数
                modelData.OutputNorm = app.OutputNorm; % 输出归一化参数
                modelData.Models = app.Models;          % 代理模型
                modelData.OutTest = app.OutTest;        % 测试集
                modelData.OutTPre = app.OutTPre;        % 测试集预测值
                modelData.ObsName = app.ObsName;      % 输出维度名

                % 保存到MAT文件
                save(fullpath, '-struct', 'modelData');

                % 显示成功消息
                uialert(app.SSIESUIFigure, ...
                    sprintf('模型参数已成功保存至:\n%s', fullpath), ...
                    '保存成功', ...
                    'Icon', 'success');

            catch ME
                % 显示错误消息
                uialert(app.SSIESUIFigure, ...
                    sprintf('保存失败:\n%s', ME.message), ...
                    '错误', ...
                    'Icon', 'error');
            end
        end

        % Menu selected function: ImportModel
        function ImportModelMenuSelected(app, event)
            app.TabTableData.SelectedTab=app.TabModelVisual;
            % 弹出文件选择对话框
            [filename, pathname] = uigetfile(...
                {'*.mat', 'MAT-files (*.mat)'}, ...
                '选择模型参数文件');

            % 检查用户是否取消选择
            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.UIFigure, '导入已取消', '操作取消', 'Icon', 'info');
                return;
            end

            % 构建完整文件路径
            fullpath = fullfile(pathname, filename);

            try
                % 加载MAT文件中的所有变量
                loadedData = load(fullpath);

                % 检查必要字段是否存在
                requiredFields = {'InParaDim', 'OutParaDim', 'InputNorm', ...
                    'OutputNorm', 'Models', 'OutTest', 'OutTPre', 'ObsName'};
                missingFields = setdiff(requiredFields, fieldnames(loadedData));

                if ~isempty(missingFields)
                    error('缺少必要字段: %s', strjoin(missingFields, ', '));
                end

                % 将加载的数据赋值给App属性
                app.InParaDim = loadedData.InParaDim;
                app.OutParaDim = loadedData.OutParaDim;
                app.InputNorm = loadedData.InputNorm;
                app.OutputNorm = loadedData.OutputNorm;
                app.Models = loadedData.Models;
                app.OutTest = loadedData.OutTest;
                app.OutTPre = loadedData.OutTPre;
                app.ObsName = loadedData.ObsName;

                % 显示成功消息
                successMessage = sprintf([...
                    '模型导入成功！\n\n'...
                    '输入维度: %d\n'...
                    '输出维度: %d\n'...
                    '模型类型: %s\n'...
                    '测试集样本数: %d'], ...
                    app.InParaDim, ...
                    app.OutParaDim, ...
                    class(app.Models{1}), ...
                    size(app.OutTest, 1));

                uialert(app.SSIESUIFigure, successMessage, '导入成功', ...
                    'Icon', 'success');

            catch ME
                % 显示错误消息
                errorMessage = sprintf('导入失败:\n%s', ME.message);
                uialert(app.SSIESUIFigure, errorMessage, '错误','Icon', 'success');

            end

            Y_test_raw=app.OutTest;
            Y_pred=app.OutTPre;

            %性能评估并显示（原始量纲）
            WritePre(app,Y_test_raw,Y_pred);

            % 在坐标区绘图
            ModelPaintUI(app);

            % 强制刷新界面
            drawnow;
        end

        % Menu selected function: BPNNMenu
        function BPNNMenuSelected(app, event)
            app.ProgreTe.Value = sprintf('程序准备中...');
            drawnow;

            if isempty(app.InputData) || isempty(app.OutputData)
                % 在App界面显示错误提示
                uialert(app.SSIESUIFigure, '请先加载输入/输出数据！', '数据缺失', 'Icon', 'error');
                return; % 直接退出当前函数
            end

            % 步骤1：导入训练数据
            input_data = app.InputData;
            output_data = app.OutputData;
            sta = tic;

            % 步骤2：划分训练集和测试集（80-20比例）
            rng(10);
            SampleNum = size(input_data,1); % 样本数
            %IPD = app.InParaDim; % 输入参数维度
            %OPD = app.OutParaDim; % 输出参数维度
            test_ratio = 0.2;
            train_ratio = 1 - test_ratio;

            idx = randperm(SampleNum);
            train_idx = idx(1:round(SampleNum*train_ratio));
            test_idx = idx(round(SampleNum*train_ratio)+1:end);

            X_train_raw = input_data(train_idx, :);  % 训练集原始数据
            Y_train_raw = output_data(train_idx, :);
            X_test_raw = input_data(test_idx, :);    % 测试集原始数据
            Y_test_raw = output_data(test_idx, :);

            %% 步骤3：数据归一化
            [X_train_norm, X_ps] = mapminmax(X_train_raw', 0, 1);
            [Y_train_norm, Y_ps] = mapminmax(Y_train_raw', 0, 1);
            X_test_norm = mapminmax('apply', X_test_raw', X_ps)';

            %% 步骤4：定义PSO优化参数
            pso_options = optimoptions('particleswarm',...
                'SwarmSize', 30, ...
                'MaxIterations', 40, ...
                'Display', 'iter',...
                'UseParallel', true);

            lb = [3,  0.001];  % 下限 [hidden_size, regularization]
            ub = [30, 0.1];    % 上限

            %% 步骤5：运行PSO优化
            app.ProgreTe.Value = sprintf('PSO参数优化中...');
            drawnow;

            best_params = particleswarm(...
                @(params) pso_fitness_cv(params, X_train_norm, Y_train_norm),...
                length(lb), lb, ub, pso_options);

            %% 步骤6：训练最终模型
            app.ProgreTe.Value = sprintf('神经网络训练中...');
            drawnow;

            optimal_hidden = round(best_params(1));
            optimal_reg = best_params(2);

            model = feedforwardnet(optimal_hidden, 'trainlm');
            model.performParam.regularization = optimal_reg;
            model.divideFcn = 'dividetrain';  % 使用全部训练数据
            model.trainParam.epochs = 1000;
            model.trainParam.goal = 1e-7;
            model.trainParam.showWindow = false;

            [model, ~] = train(model, X_train_norm, Y_train_norm);

            %% 步骤7：测试集预测
            Y_pred_norm = model(X_test_norm');
            Y_pred = mapminmax('reverse', Y_pred_norm, Y_ps)';
            ela = toc(sta);
            app.ProgreTe.Value = sprintf(['模型训练完成！\n训练时间：%.1f秒'],ela);

            %% 保存模型和结果
            app.Models = model;
            app.InputNorm = X_ps;
            app.OutputNorm = Y_ps;
            app.OutTest = Y_test_raw;
            app.OutTPre = Y_pred;

            %性能评估并显示（原始量纲）
            WritePre(app,Y_test_raw,Y_pred);

            % 在坐标区绘图
            ModelPaintUI(app);
            drawnow;

            %% ------------------ 五折交叉验证适应度函数 ------------------ %%
            function fitness = pso_fitness_cv(params, X, Y)
                k = 5;
                cv = cvpartition(size(X,2), 'KFold', k);
                mse_scores = zeros(k,1);

                for fold = 1:k
                    % 划分训练/验证集
                    trainIdx = training(cv, fold);
                    valIdx = test(cv, fold);

                    % 训练模型
                    hidden_size = round(params(1));
                    reg_coeff = params(2);

                    net = feedforwardnet(hidden_size, 'trainlm');
                    net.performParam.regularization = reg_coeff;
                    net.trainParam.showWindow = false;
                    net.trainParam.epochs = 100;
                    net.trainParam.goal = 1e-5;

                    [net, ~] = train(net, X(:,trainIdx), Y(:,trainIdx));

                    % 预测验证集
                    Y_val_pred = net(X(:,valIdx));

                    % 计算MSE
                    mse_scores(fold) = mean((Y(:,valIdx) - Y_val_pred).^2, 'all');
                end

                fitness = mean(mse_scores);
            end
        end

        % Menu selected function: MARSMenu
        function MARSMenuSelected(app, event)
            app.ProgreTe.Value = sprintf('程序准备中...');
            drawnow;

            if isempty(app.InputData) || isempty(app.OutputData)
                % 在App界面显示错误提示
                uialert(app.SSIESUIFigure, '请先加载输入/输出数据！', '数据缺失', 'Icon', 'error');
                return; % 直接退出当前函数
            end

            appRoot = fileparts(mfilename('fullpath'));
            folder = fullfile(appRoot,'ARESLab');     % 获取当前文件夹的绝对路径
            addpath(folder);  % 将当前文件夹添加到搜索路径中
            % 步骤1：导入训练数据
            input_data = app.InputData;
            output_data = app.OutputData;
            sta = tic;

            % 步骤2：划分训练集和测试集（80-20比例）
            rng(10);
            SampleNum = size(input_data,1);
            %IPD = app.InParaDim;
            %OPD = app.OutParaDim;
            test_ratio = 0.2;
            train_ratio = 1 - test_ratio;

            idx = randperm(SampleNum);
            train_idx = idx(1:round(SampleNum*train_ratio));
            test_idx = idx(round(SampleNum*train_ratio)+1:end);

            X_train_raw = input_data(train_idx, :);
            Y_train_raw = output_data(train_idx, :);
            X_test_raw = input_data(test_idx, :);
            Y_test_raw = output_data(test_idx, :);

            %% 步骤3：数据归一化
            [X_train_norm, X_ps] = mapminmax(X_train_raw', 0, 1);
            X_train_norm = X_train_norm';
            X_test_norm = mapminmax('apply', X_test_raw', X_ps)';

            [Y_train_norm, Y_ps] = mapminmax(Y_train_raw', 0, 1);
            Y_train_norm = Y_train_norm';

            %% 步骤4：运行PSO优化
            lb = [4.5, 1.0, 1];   % [maxFuncs, c, maxInteractions]
            ub = [30.5, 5.0, 2];

            pso_options = optimoptions('particleswarm',...
                'SwarmSize', 30,...
                'MaxIterations', 40,...
                'Display', 'iter',...
                'UseParallel', true);

            app.ProgreTe.Value = sprintf('PSO参数优化中...\n进度：0%%');
            drawnow;

            best_params = particleswarm(...
                @(params) pso_fitness_cv(params, X_train_norm, Y_train_norm),...
                length(lb), lb, ub, pso_options);

            %% 步骤5：训练最终MARS模型
            app.ProgreTe.Value = sprintf('MARS模型训练中...');
            drawnow;

            trainParams = aresparams();
            trainParams.cubic = true;
            trainParams.maxFuncs = round(best_params(1));
            trainParams.c = best_params(2);
            trainParams.maxInteractions = round(best_params(3));
            trainParams.useMinSpan = true;

            model = aresbuild(X_train_norm, Y_train_norm, trainParams);

            %% 步骤6：测试集预测
            Y_pred_norm = arespredict(model, X_test_norm);
            Y_pred = mapminmax('reverse', Y_pred_norm', Y_ps)';
            ela = toc(sta);
            app.ProgreTe.Value = sprintf(['模型训练完成！\n训练时间：%.1f秒'],ela);


            %% 保存模型和结果
            app.Models = {model};  % 保持与GPR相同的cell结构
            app.InputNorm = X_ps;
            app.OutputNorm = Y_ps;
            app.OutTest = Y_test_raw;
            app.OutTPre = Y_pred;

            %性能评估并显示（原始量纲）
            WritePre(app,Y_test_raw,Y_pred);

            % 在坐标区绘图
            ModelPaintUI(app);
            drawnow;

            %% ------------------ 五折交叉验证适应度函数 ------------------ %%
            function fitness = pso_fitness_cv(params, X, Y)
                k = 5;
                cv = cvpartition(size(X,1), 'KFold', k);
                mse_scores = zeros(k,1);

                for fold = 1:k
                    % 划分训练/验证集
                    trainIdx = training(cv, fold);
                    valIdx = test(cv, fold);

                    % 设置MARS参数
                    Params = aresparams();
                    Params.cubic = true;
                    Params.maxFuncs = round(params(1));
                    Params.c = params(2);
                    Params.maxInteractions = round(params(3));
                    Params.useMinSpan = true;

                    % 训练模型
                    modelf = aresbuild(X(trainIdx,:), Y(trainIdx,:), Params);

                    % 预测验证集
                    YP = arespredict(modelf, X(valIdx,:));

                    % 计算MSE
                    mse_scores(fold) = mean((Y(valIdx,:) - YP).^2, 'all');
                end

                fitness = mean(mse_scores);
            end
        end

        % Callback function
        function ModelPreButtonPushed(app, event)

        end

        % Button pushed function: MDimPre
        function MDimPreButtonPushed(app, event)
            prow = floor(sqrt(app.OutParaDim));
            pcolum = ceil(sqrt(app.OutParaDim));
            if prow*pcolum < app.OutParaDim
                prow = prow+1;
            end
            mdpfig=figure('Name', '各维度训练结果','Position', [50, 50, 700, 700]);
            t = tiledlayout(mdpfig,prow,pcolum);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            xlabel(t,'数值模拟计算值 (mm)','FontSize',15)
            ylabel(t,'代理模型预测值 (mm)','FontSize',15)
            zhan=app.ObsName;
            for i=1:app.OutParaDim
                nexttile;
                xticks('manual');
                Y_test=app.OutTest(:,i);
                Y_pred1=app.OutTPre(:,i);
                xiao=floor(min(Y_test,[],'all'));da=ceil(max(Y_test,[],'all'));
                plot([xiao da], [xiao da], 'k-', 'LineWidth', 1.2,'HandleVisibility', 'off');
                hold on;
                scatter(Y_test(:), Y_pred1(:), 45,'filled','s','MarkerFaceColor', app.RgbColor(i,:));
                text(xiao+0.1*(da-xiao), da-0.1*(da-xiao),zhan(i),'Color', app.RgbColor(i,:), 'FontSize',12, 'FontWeight','bold');
                xlim([xiao da]);
                ylim([xiao da]);
                xticks([xiao (xiao+da)/2 da]);
                yticks([xiao (xiao+da)/2 da]);
                set(gca, 'FontSize',12);
                grid off; axis square;
                pbaspect([1 1 1]);
                box on;
            end
        end

        % Button pushed function: DimR2
        function DimR2Pushed(app, event)
            % 计算r2
            Y_test_raw=app.OutTest;
            Y_pred=app.OutTPre;
            ss_total = sum((Y_test_raw - mean(Y_test_raw)).^2, 1);
            ss_residual = sum((Y_test_raw - Y_pred).^2, 1);
            r2_per_dim = 1 - ss_residual./ss_total;
            % 绘制 R^2 图表
            r2fig=figure('Name', '各维度决定系数R^2');
            PN=app.ObsName;
            X = categorical(PN);
            X = reordercats(X,PN);
            bar(X,r2_per_dim,'EdgeColor',[1 1 1],'FaceColor', [0.3010 0.7450 0.9330]);
            ylabel('R^2','FontSize',13);
            set(gca, 'FontSize',13);
            ylim([min(r2_per_dim)-0.003 1]);
            %pbaspect([1.5 1 1]);
            grid on;
        end

        % Button pushed function: DimRMSEButton
        function DimRMSEButtonPushed(app, event)
            % 计算RMSE
            Y_test_raw=app.OutTest;
            Y_pred=app.OutTPre;
            RMSEDim = sqrt(mean((Y_test_raw - Y_pred).^2, 1));
            % 绘制 RMSE 图表
            RMSEfig=figure('Name', '各维度RMSE');
            PN=app.ObsName;
            X = categorical(PN);
            X = reordercats(X,PN);
            bar(X,RMSEDim,'EdgeColor',[1 1 1],'FaceColor', app.RgbColor(24,:));
            ylabel('RMSE(mm)','FontSize',13);
            set(gca, 'FontSize',13);
            %ylim([min(RMSEDim)-0.005 1]);
            %pbaspect([1.5 1 1]);
            grid on;
        end

        % Button pushed function: MaxErrButton
        function MaxErrButtonPushed(app, event)
            % 计算RMSE
            Y_test_raw=app.OutTest;
            Y_pred=app.OutTPre;
            err=Y_pred-Y_test_raw;
            maxer=MaxSign(app,err);
            % 绘制 RMSE 图表
            maxfig=figure('Name', '各维度最大预测误差');
            PN=app.ObsName;
            X = categorical(PN);
            X = reordercats(X,PN);
            y=zeros(1,35);x=0:0.5:app.OutParaDim+1;
            plot(x,y,'k--','LineWidth', 1.5,'HandleVisibility', 'off');
            hold on;
            bar(X,maxer,'EdgeColor',[1 1 1],'FaceColor', app.RgbColor(18,:));
            ylabel('最大预测误差(mm)','FontSize',13);
            set(gca, 'FontSize',13);
            %m=max(abs(err));
            %ylim([ceil(m,1) 1]);
            %pbaspect([1.5 1 1]);
            grid on;
        end

        % Menu selected function: MHMenu
        function MHMenuSelected(app, event)
            app.TabTableData.SelectedTab=app.TabParaInversion;
            figure(app.SSIESUIFigure);
            % 判断数据是否齐全
            if isempty(app.PriorValue)
                % 在App界面显示错误提示
                uialert(app.SSIESUIFigure, '请正确加载参数先验数据', '数据缺失', 'Icon', 'error');
                return; % 直接退出当前函数
            elseif isempty(app.ObsData)
                uialert(app.SSIESUIFigure, '请正确加载观测数据', '数据缺失', 'Icon', 'error');
                return;
            elseif isempty(app.ObsError)
                uialert(app.SSIESUIFigure, '请正确加载观测误差数据', '数据缺失', 'Icon', 'error');
                return;
            elseif isempty(app.Models)
                uialert(app.SSIESUIFigure, '请训练或导入代理模型', '模型缺失', 'Icon', 'error');
                return;
            end
            if app.InParaDim > (size(app.PriorValue, 2)+size(app.ExcaChara, 2))
                uialert(app.SSIESUIFigure, '请正确加载开挖特征数据', '数据缺失', 'Icon', 'error');
                return;
            end

            % 开始反演流程
            app.DistributionDropDown.Enable = 'off';
            app.ModelDropDown.Enable = 'off';
            sta=tic;
            % 提取位移观测数据
            dis_real=app.ObsData';  % 观测数据，每一列是一个开挖阶段的监测数据
            error_vector = app.ObsError';   % 观测误差，第一列是误差均值，第二列是误差标准差

            % 定义所采用站点数据的索引，前8列表示8个GD的垂直方向位移，后8列表示8个M的位移
            columns_to_extract = (1:app.OutParaDim);

            % 提取指定列的数据参与贝叶斯反分析
            bayesian_input_obs = (dis_real( columns_to_extract,:));
            bayesian_input_std=(error_vector( columns_to_extract,2));

            % 定义贝叶斯更新的基本参数
            obsData=[bayesian_input_obs(:,1),bayesian_input_std(:,1)];

            [obsPeriods,~]=size(app.ObsData);   % 定义开挖期数
            %obsPeriods = obsPeriods - 1;%反演开挖步骤-1次，最后一次用于预测
            geoData = app.PriorValue';
            prior_mean = geoData(:,1);    % 定义先验分布的均值
            prior_std = geoData(:,2);    % 定义先验分布的标准差
            [parNum,~]=size(prior_mean);   % parNum为不确定性参数的数量
            resultsUpParam(:,1)=prior_mean;   % 定义参数更新估值的存储矩阵，第一列为先验值
            resultsUpParamStd(:,1)=prior_std;   % 定义参数更新标准差的存储矩阵，第一列为先验值
            input = [zeros(obsPeriods, parNum),app.ExcaChara(1:obsPeriods,:)];   %作为模型输入
            inputW=input(1,:);   % 定义代理模型的输入数组，行向量

            % 贝叶斯更新
            for i=1:obsPeriods
                app.InverTextArea.Value = sprintf('%d个不确定性参数，%d个反演阶段...\n正在进行第%d阶段反演...',parNum,obsPeriods,i);
                %更新观测
                obsData(:,1)=bayesian_input_obs(:,i);   %提取位移观测值
                inputW = input(i,:);    %开挖特征
                %更新分布
                [params,expectation_param,std_param] = MHupdate(app, geoData,obsData,inputW,i,columns_to_extract);
                %估计值复用
                geoData(:,1)=expectation_param;
                geoData(:,2)=std_param;
                %存储参数
                resultsUpParam(:,i+1)=expectation_param;
                resultsUpParamStd(:,i+1)=std_param;
                resultsUpParamCell{i}=params;
            end
            elapse=toc(sta);
            app.InverTextArea.Value = sprintf('%d个不确定性参数，%d个反演阶段...\n耗时%d秒，反演完毕！',parNum,obsPeriods,elapse);
            app.ResultPara = resultsUpParam';
            app.ResultStd = resultsUpParamStd';
            app.ResultCell = resultsUpParamCell;
            % 显示在表格控件
            app.PosterLabel.Text = '不确定参数后验均值';
            app.PosterParaTable.ColumnName(2:1+parNum) = app.GeoPara;
            pha=1:1:obsPeriods;
            pha=string(pha');
            pha=strcat('后验',pha);
            peri=["先验";pha];
            app.PosterParaTable.Data=[peri, app.ResultPara];

            % 解锁控件
            app.DistributionDropDown.Enable = 'on';
            app.ModelDropDown.Enable = 'on';
            app.PaintParaDropDown.Items=app.GeoPara;
            app.PreDisDropDown.Items=app.ObsName;
        end

        % Menu selected function: DREAMMenu
        function DREAMMenuSelected(app, event)
            app.TabTableData.SelectedTab=app.TabParaInversion;
            figure(app.SSIESUIFigure);
            % 判断数据是否齐全
            if isempty(app.PriorValue)
                % 在App界面显示错误提示
                uialert(app.SSIESUIFigure, '请正确加载参数先验数据', '数据缺失', 'Icon', 'error');
                return; % 直接退出当前函数
            elseif isempty(app.ObsData)
                uialert(app.SSIESUIFigure, '请正确加载观测数据', '数据缺失', 'Icon', 'error');
                return;
            elseif isempty(app.ObsError)
                uialert(app.SSIESUIFigure, '请正确加载观测误差数据', '数据缺失', 'Icon', 'error');
                return;
            elseif isempty(app.Models)
                uialert(app.SSIESUIFigure, '请训练或导入代理模型', '模型缺失', 'Icon', 'error');
                return;
            end
            if app.InParaDim > (size(app.PriorValue, 2)+size(app.ExcaChara, 2))
                uialert(app.SSIESUIFigure, '请正确加载开挖特征数据', '数据缺失', 'Icon', 'error');
                return;
            end

            originalFolder = pwd;
            appRoot = fileparts(mfilename('fullpath'));
            deramFolder = fullfile(appRoot, 'DERAMalgorithm');
            cd(appRoot);
            % 获取 DERAMalgorithm 及其所有子文件夹的路径
            allSubfolders = genpath(deramFolder);
            % 添加到 MATLAB 路径
            addpath(allSubfolders);

            % 开始反演流程
            app.DistributionDropDown.Enable = 'off';
            app.ModelDropDown.Enable = 'off';
            sta=tic;
            % 提取位移观测数据
            dis_real=app.ObsData';  % 观测数据，每一列是一个开挖阶段的监测数据
            error_vector = app.ObsError';   % 观测误差，第一列是误差均值，第二列是误差标准差

            % 定义所采用站点数据的索引，前8列表示8个GD的垂直方向位移，后8列表示8个M的位移
            columns_to_extract = (1:app.OutParaDim);

            % 提取指定列的数据参与贝叶斯反分析
            bayesian_input_obs = (dis_real( columns_to_extract,:));
            bayesian_input_std=(error_vector( columns_to_extract,2));

            % 定义贝叶斯更新的基本参数
            obsData=[bayesian_input_obs(:,1),bayesian_input_std(:,1)];

            [obsPeriods,~]=size(app.ObsData);   % 定义开挖期数
            %obsPeriods = obsPeriods - 1;%反演开挖步骤-1次，最后一次用于预测
            geoData = app.PriorValue';
            prior_mean = geoData(:,1);    % 定义先验分布的均值
            prior_std = geoData(:,2);    % 定义先验分布的标准差
            [parNum,~]=size(prior_mean);   % parNum为不确定性参数的数量
            resultsUpParam(:,1)=prior_mean;   % 定义参数更新估值的存储矩阵，第一列为先验值
            resultsUpParamStd(:,1)=prior_std;   % 定义参数更新标准差的存储矩阵，第一列为先验值
            input = [zeros(obsPeriods, parNum),app.ExcaChara(1:obsPeriods,:)];   %作为模型输入
            inputW=input(1,:);   % 定义代理模型的输入数组，行向量

            % 贝叶斯更新
            for i=1:obsPeriods
                app.InverTextArea.Value = sprintf('%d个不确定性参数，%d个反演阶段...\n正在进行第%d阶段反演...',parNum,obsPeriods,i);
                drawnow;
                %更新观测
                obsData(:,1)=bayesian_input_obs(:,i);   %提取位移观测值
                inputW = input(i,:);    %开挖特征
                %更新分布
                [params,expectation_param,std_param] = DREAMupdate(app, geoData,obsData,inputW,i,columns_to_extract);
                %估计值复用
                geoData(:,1)=expectation_param;
                geoData(:,2)=std_param;
                %存储参数
                resultsUpParam(:,i+1)=expectation_param;
                resultsUpParamStd(:,i+1)=std_param;
                resultsUpParamCell{i}=params;
            end
            elapse=toc(sta);
            app.InverTextArea.Value = sprintf('%d个不确定性参数，%d个反演阶段...\n耗时%.0f秒，反演完毕！',parNum,obsPeriods,elapse);
            app.ResultPara = resultsUpParam';
            app.ResultStd = resultsUpParamStd';
            app.ResultCell = resultsUpParamCell;
            % 显示在表格控件
            app.PosterLabel.Text = '不确定参数后验均值';
            app.PosterParaTable.ColumnName(2:1+parNum) = app.GeoPara;
            pha=1:1:obsPeriods;
            pha=string(pha');
            pha=strcat('后验',pha);
            peri=["先验";pha];
            app.PosterParaTable.Data=[peri, app.ResultPara];

            cd(originalFolder);
            restoredefaultpath
            % 解锁控件
            app.DistributionDropDown.Enable = 'on';
            app.ModelDropDown.Enable = 'on';
            app.PaintParaDropDown.Items=app.GeoPara;
            app.PreDisDropDown.Items=app.ObsName;
        end

        % Menu selected function: SaveInverMenu
        function SaveInverMenuSelected(app, event)
            if isempty(app.ResultPara)
                uialert(app.SSIESUIFigure, '请先运行反演计算', '数据缺失', 'Icon', 'error');
                return;
            end
            % 列名编辑
            geoname=cell(1,size(app.PriorValue,2)+1);
            geoname{1}='更新阶段';
            geoname(2:end)=app.GeoPara;
            % 表格内容
            % 表1，参数后验均值
            obsperiods=size(app.ObsData,1);
            pha=1:1:obsperiods;
            pha=string(pha');
            pha=strcat('后验',pha);
            peri=["先验";pha];
            postmean=[peri, app.ResultPara];
            % 表2， 参数后验标准差
            posterstd=[peri, app.ResultStd];
            % 表3，参数变异系数
            coov=[peri, app.ResultStd./app.ResultPara];

            % 让用户选择保存位置
            [filename, pathname] = uiputfile(...
                {'*.xlsx', 'Excel 文件 (*.xlsx)'}, ...
                '保存反演结果', ...
                'InversionResults.xlsx'); % 默认文件名

            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.SSIESUIFigure, '用户取消保存', '操作取消', 'Icon', 'info');
                return;
            end

            % 获取完整文件路径
            fullpath = fullfile(pathname, filename);
            [~, ~, ext] = fileparts(fullpath);

            try
                % 根据文件类型执行不同保存操作
                switch lower(ext)
                    case '.xlsx'
                        % 保存为Excel文件（带列名）
                        writetable(array2table(postmean, 'VariableNames', geoname), ...
                            fullpath, 'Sheet', '参数均值');
                        writetable(array2table(posterstd, 'VariableNames', geoname), ...
                            fullpath, 'Sheet', '参数标准差');
                        writetable(array2table(coov, 'VariableNames', geoname), ...
                            fullpath, 'Sheet', '参数变异系数');

                    otherwise
                        error('不支持的文件格式');
                end

                % 显示成功消息
                successMsg = sprintf([...
                    '反演结果已成功保存至:\n%s\n\n'...
                    '包含以下数据:\n'...
                    '- 参数均值\n'...
                    '- 参数标准差\n'...
                    '- 参数变异系数'], ...
                    fullpath);

                uialert(app.SSIESUIFigure, successMsg, '保存成功', 'Icon', 'success');

            catch ME
                % 显示错误消息
                errMsg = sprintf('保存失败:\n%s\n\n错误详情:\n%s', ...
                    fullpath, ME.message);
                uialert(app.SSIESUIFigure, errMsg, '保存错误', 'Icon', 'error');
            end
        end

        % Close request function: SSIESUIFigure
        function SSIESUIFigureCloseRequest(app, event)

            clear global;
            app.cleanupResources();
            close all force hidden; % 关闭所有图形窗口（包括隐藏的）
            delete(app)

        end

        % Value changed function: ChoseTableDropDown
        function ChoseTableDropDownValueChanged(app, event)
            if isempty(app.ResultPara)
                uialert(app.SSIESUIFigure, '请先运行反演计算', '数据缺失', 'Icon', 'error');
                return;
            end
            % 列名编辑
            geoname=cell(1,size(app.PriorValue,2)+1);
            geoname{1}='更新阶段';
            geoname(2:end)=app.GeoPara;
            % 表格内容
            % 表1，参数后验均值
            obsperiods=size(app.ObsData,1);
            pha=1:1:obsperiods;
            pha=string(pha');
            pha=strcat('后验',pha);
            peri=["先验";pha];
            postmean=[peri, app.ResultPara];
            % 表2， 参数后验标准差
            posterstd=[peri, app.ResultStd];
            % 表3，参数变异系数
            coov=[peri, app.ResultStd./app.ResultPara];

            app.PosterParaTable.ColumnName=geoname;
            value = app.ChoseTableDropDown.Value;
            switch value
                case '参数均值'
                    app.PosterParaTable.Data=postmean;
                    app.PosterLabel.Text='参数后验均值变化';
                case '参数标准差'
                    app.PosterParaTable.Data=posterstd;
                    app.PosterLabel.Text='参数后验标准差变化';
                case '变异系数'
                    app.PosterParaTable.Data=coov;
                    app.PosterLabel.Text='参数变异系数变化';
            end
        end

        % Button pushed function: SamplingDiaButton
        function SamplingDiaButtonPushed(app, event)
            % 获取绘图时期输入并替换全角逗号
            str = app.ExcaEditField.Value;
            str = strrep(str, '，', ','); % 替换全角逗号
            str = strrep(str, ' ', '');  % 移除空格
            period = str2num(str); % 转换为数组
            period=period;
            if isempty(period) || ~isnumeric(period)
                period=2:1:size(app.ResultPara,1)-1;
            end
            if min(period)<2||max(period)>size(app.ResultPara,1)
                uialert(app.SSIESUIFigure, '输入时期数超出范围', '数据错误', 'Icon', 'error');
                return;
            end


            % 获取待绘图岩土参数
            selectedItem = app.PaintParaDropDown.Value;  % 获取当前选中的文本
            itemsList = app.PaintParaDropDown.Items;     % 获取所有选项的字符串数组
            index = find(strcmp(itemsList, selectedItem)); % 返回匹配项的索引

            %绘图
            Dsample=app.ResultCell{max(period)}(index,:);
            Dmean=app.ResultPara(max(period),index);
            Dstd=app.ResultStd(max(period),index);
            [mu,sigma] = OcalDisP(app,Dmean,Dstd);

            delete(app.PosterParaUIAxes.Children);  % 直接删除所有图形对象
            cla(app.PosterParaUIAxes, 'reset');

            % 设置坐标轴样式
            box(app.PosterParaUIAxes, 'on');
            set(app.PosterParaUIAxes, 'FontSize', 13);
            hold(app.PosterParaUIAxes, 'on');
            grid(app.PosterParaUIAxes, 'on');

            % 左侧Y轴：绘制直方图
            yyaxis(app.PosterParaUIAxes, 'left');
            ylabel(app.PosterParaUIAxes, '样本数量');
            title(app.PosterParaUIAxes, ['后验 ',num2str(max(period))]);
            xlabel(app.PosterParaUIAxes, app.PaintParaDropDown.Value); % 使用下拉框的值作为X轴标签
            histogram(app.PosterParaUIAxes, Dsample); % 在指定坐标轴中绘制直方图

            % 右侧Y轴：绘制概率密度曲线
            yyaxis(app.PosterParaUIAxes, 'right');
            ylabel(app.PosterParaUIAxes, '概率密度');
            x = linspace(exp(mu - 3*sigma), exp(mu + 3*sigma), 50); % x范围
            y = lognpdf(x, mu, sigma); % 对数正态分布的概率密度
            plot(app.PosterParaUIAxes, x, y, 'LineWidth', 2.0, 'DisplayName', '概率密度曲线', 'Color', 'r');

            % 关闭 hold 状态
            hold(app.PosterParaUIAxes, 'off');
        end

        % Button pushed function: PDFDiaButton
        function PDFDiaButtonPushed(app, event)
            % 获取绘图时期输入并替换全角逗号
            str = app.ExcaEditField.Value;
            str = strrep(str, '，', ','); % 替换全角逗号
            str = strrep(str, ' ', '');  % 移除空格
            period = str2num(str); % 转换为数组
            period=period+1;
            if isempty(period) || ~isnumeric(period)
                period=1:1:size(app.ResultPara,1);
            end
            if min(period)<1||max(period)>size(app.ResultPara,1)
                uialert(app.SSIESUIFigure, '输入时期数超出范围', '数据错误', 'Icon', 'error');
                return;
            end


            % 获取待绘图岩土参数
            selectedItem = app.PaintParaDropDown.Value;  % 获取当前选中的文本
            itemsList = app.PaintParaDropDown.Items;     % 获取所有选项的字符串数组
            index = find(strcmp(itemsList, selectedItem)); % 返回匹配项的索引

            %绘图
            Dmean=app.ResultPara(period,index);
            Dstd=app.ResultStd(period,index);
            [mu,sigma] = OcalDisP(app,Dmean,Dstd);

            % 自动生成 DisplayName
            DisplayNames = cell(1, length(period));
            for i = 1:length(period)
                if period(i) == 1
                    DisplayNames{i} = '先验'; % 先验分布
                else
                    DisplayNames{i} = ['后验 ', num2str(period(i) - 1)]; % 第n次更新
                end
            end

            % 设置颜色
            colors = lines(length(period));

            % 清除并准备坐标轴
            delete(app.PosterParaUIAxes.Children);  % 直接删除所有图形对象
            cla(app.PosterParaUIAxes, 'reset');

            hold(app.PosterParaUIAxes, 'on'); % 保持图形

            % 绘制概率密度曲线
            for i = 1:length(period)
                % 生成概率密度曲线
                x = linspace(exp(mu(i) - 3*sigma(i)), exp(mu(i) + 3*sigma(i)), 50); % x范围
                y = lognpdf(x, mu(i), sigma(i)); % 对数正态分布的概率密度
                plot(app.PosterParaUIAxes, x, y, '-','LineWidth', 1.5, 'DisplayName', DisplayNames{i}, 'Color', colors(i, :));
            end

            drawnow;
            % 设置坐标轴标签和图例
            xlabel(app.PosterParaUIAxes, app.PaintParaDropDown.Value); % 横坐标
            ylabel(app.PosterParaUIAxes, '概率密度'); % 纵坐标
            legend(app.PosterParaUIAxes, 'show', 'Location', 'northeast', 'Box', 'on'); % 图例

            % 设置坐标轴样式
            box(app.PosterParaUIAxes, 'on');
            grid(app.PosterParaUIAxes, 'on');
            hold(app.PosterParaUIAxes, 'off');
        end

        % Button pushed function: COVDiaButton
        function COVDiaButtonPushed(app, event)
            % 获取绘图时期输入并替换全角逗号
            str = app.ExcaEditField.Value;
            str = strrep(str, '，', ','); % 替换全角逗号
            str = strrep(str, ' ', '');  % 移除空格
            period = str2num(str); % 转换为数组
            period=sort(period);
            period=period+1;
            if isempty(period) || ~isnumeric(period)
                period=1:1:size(app.ResultPara,1);
            end
            if min(period)<1||max(period)>size(app.ResultPara,1)
                uialert(app.SSIESUIFigure, '输入时期数超出范围', '数据错误', 'Icon', 'error');
                return;
            end


            % 获取待绘图岩土参数
            selectedItem = app.PaintParaDropDown.Value;  % 获取当前选中的文本
            itemsList = app.PaintParaDropDown.Items;     % 获取所有选项的字符串数组
            index = find(strcmp(itemsList, selectedItem)); % 返回匹配项的索引

            %绘图
            pcov=app.ResultStd(period,index)./app.ResultPara(period,index);

            % 自动生成x轴标签
            p = cell(1, length(period));
            for i = 1:length(period)
                if period(i) == 1
                    p{i} = '先验'; % 先验分布
                else
                    p{i} = ['后验 ', num2str(period(i) - 1)]; % 第n次更新
                end
            end
            P = categorical(p);
            P = reordercats(P, p); % 保持原始顺序

            % 创建颜色映射
            colors = lines(length(period));

            % 绘制条形图
            delete(app.PosterParaUIAxes.Children);  % 直接删除所有图形对象
            cla(app.PosterParaUIAxes, 'reset');


            hold(app.PosterParaUIAxes, 'on');
            box(app.PosterParaUIAxes, 'on');

            b = bar(app.PosterParaUIAxes, P, pcov, 'EdgeColor', [1 1 1], 'FaceColor', 'flat');
            b.CData = colors; % 为每个条形指定不同颜色

            % 设置标签和标题
            ylabel(app.PosterParaUIAxes, '变异系数变化');
            title(app.PosterParaUIAxes, app.PaintParaDropDown.Value);

            % 如果需要绘制连线（假设y是您的连线数据）
            x = b.XEndPoints; % 获取条形中心x坐标
            plot(app.PosterParaUIAxes, x, pcov, 'k-s', 'LineWidth', 2, ...
                'MarkerFaceColor', 'k', 'MarkerSize', 6, 'HandleVisibility', 'off');

            hold(app.PosterParaUIAxes, 'off');
        end

        % Button pushed function: allPDFButton
        function allPDFButtonPushed(app, event)
            pnum=size(app.ResultPara,2);
            prow = floor(sqrt(pnum));
            pcolum = ceil(sqrt(pnum));
            if prow*pcolum < size(app.ResultPara,2)
                prow = prow+1;
            end
            pdffig=figure('Name', '总体概率密度曲线');
            t = tiledlayout(pdffig,prow,pcolum);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';

            Dmean=app.ResultPara;Dstd=app.ResultStd;
            [Dmu,Dsigma]=OcalDisP(app,Dmean,Dstd);
            ylabel(t,'后验分布概率密度函数','FontSize',16);
            para=app.GeoPara;

            for i=1:pnum
                nexttile;
                hold on;

                x = linspace(exp(Dmu(1,i) - 3*Dsigma(1,i)), exp(Dmu(1,i) + 3*Dsigma(1,i)), 50); % x范围
                y = lognpdf(x, Dmu(1,i), Dsigma(1,i)); % 对数正态分布的概率密度
                plot(x, y,'--','LineWidth', 2.0, 'DisplayName', '先验分布', 'Color', 'k');
                str = sprintf('\\mu=%.3f\n\\sigma=%.3f', round(Dmean(1,i),3), round(Dstd(1,i),3));
                % 添加文本并启用 LaTeX 解释器
                text(0.98, 0.95, str, ...
                    'Units', 'normalized', ...
                    'Color', 'k', ...
                    'FontSize', 14, ...
                    'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', ...
                    'Interpreter', 'tex'); % 关键！启用 LaTeX 解释器
                hold on;

                x = linspace(exp(Dmu(end,i) - 3*Dsigma(end,i)), exp(Dmu(end,i) + 3*Dsigma(end,i)), 50); % x范围
                y = lognpdf(x, Dmu(end,i), Dsigma(end,i)); % 对数正态分布的概率密度
                plot(x, y,'-','LineWidth', 2.0, 'DisplayName', '最新后验分布', 'Color', '#D95319');
                str = sprintf('\\mu=%.3f\n\\sigma=%.3f', round(Dmean(end,i),3), round(Dstd(end,i),3));
                % 添加文本并启用 LaTeX 解释器
                text(0.98, 0.75, str, ...
                    'Units', 'normalized', ...
                    'Color', '#D95319', ...
                    'FontSize', 14, ...
                    'HorizontalAlignment', 'right', ...
                    'VerticalAlignment', 'top', ...
                    'Interpreter', 'tex'); % 关键！启用 LaTeX 解释器
                hold on;

                set(gca, 'FontSize',14);
                xlabel(para(i),'FontSize',16);
                grid off;
                pbaspect([1.4 1 1]);
                box on;

                if i==ceil(pcolum/2)
                    legend('Location', 'northoutside','Orientation', 'horizontal','FontSize',16);
                end
            end
        end

        % Button pushed function: allCOVButton
        function allCOVButtonPushed(app, event)
            PCOV=app.ResultStd./app.ResultPara;
            ylabel('变异系数','FontSize',16);
            stage = cell(1, size(PCOV,1));
            for i = 1:size(PCOV,1)
                if i == 1
                    stage{i} = '先验'; % 先验分布
                else
                    stage{i} = ['后验 ', num2str(i - 1)]; % 第n次更新
                end
            end

            hold on;
            set(gca, 'FontSize',16);
            box on;
            X = categorical(app.GeoPara);
            X = reordercats(X,app.GeoPara);
            B=bar(X,PCOV','EdgeColor',[1 1 1],'FaceColor', 'flat');
            colors = lines(size(PCOV,1));
            for k = 1:numel(B)
                B(k).CData = colors(k,:);
            end
            legend(stage, 'Location', 'northoutside','Orientation', 'horizontal','FontSize',16);
            w=size(PCOV,1)*size(PCOV,2)/14;
            pbaspect([w 1 1]);
            for i = 1:size(PCOV,2) % 遍历组
                % 提取当前组内所有柱子的x,y坐标
                x = zeros(1, size(PCOV, 1));
                y = PCOV(:, i);
                for j = 1:size(PCOV, 1) % 遍历当前组的柱子
                    x(j) = B(j).XEndPoints(i); % 第j个颜色柱子在第i组的x坐标
                end
                % 绘制连线（黑色实线）
                plot(x, y, 'k-s', 'LineWidth', 2, 'MarkerFaceColor', 'k', 'MarkerSize', 6,'HandleVisibility', 'off')
            end
        end

        % Menu selected function: PrediMenu
        function PrediMenuSelected(app, event)
            if ~isempty(app.ExcaChara) && size(app.ExcaChara,1)<=size(app.ObsData,1)
                uialert(app.SSIESUIFigure, '缺少下一阶段的开挖特征', '数据缺失', 'Icon', 'error');
                return;
            end
            per=size(app.ObsData,1)+1;
            inputpre=[app.ResultPara(per,:),app.ExcaChara(per,:)];
            app.PredictDis = SurrogateModel(app,inputpre);
            app.PreLabel.Text='下阶段边坡施工预测位移';
            % 生成表头
            obsname=cell(1,size(app.ObsData,2)+1);
            obsname{1}='预测时期';
            obsname(1,2:end)=app.ObsName;
            app.SlopeTable.ColumnName=obsname;
            % 填充数据
            b1=['No.',num2str(per)];
            da=[string(b1),app.PredictDis];
            app.SlopeTable.Data=da;
        end

        % Button down function: SlopeUIAxes
        function SlopeUIAxesButtonDown(app, event)

        end

        % Button pushed function: AllDisButton
        function AllDisButtonPushed(app, event)
            prow = floor(sqrt(app.OutParaDim));
            pcolum = ceil(sqrt(app.OutParaDim));
            if prow*pcolum < app.OutParaDim
                prow = prow+1;
            end
            apdfig=figure('Name', '下阶段预测位移','Position', [50, 50, 700, 700]);
            t = tiledlayout(apdfig,prow,pcolum);
            t.TileSpacing = 'compact';
            t.Padding = 'compact';
            ylabel(t,'位移 (mm)','FontSize',15)
            zhan=app.ObsName;
            opernum = size(app.ObsData, 1);
            x = 1:1:opernum;
            for i=1:app.OutParaDim
                nexttile;
                % 设置X轴刻度
                xticks(1:1:opernum+1);
                %                 xla = strcat('No.', string(x'));
                %                 newest = '预测';
                %                 xla = [string(xla'), string(newest)];
                %                 xticklabels(xla);
                xlim([0 opernum+2]);


                y = app.ObsData(:, i)';

                % 在app.SlopeUIAxes中绘图
                hold('on');

                % 绘制观测数据线
                plot(x, y, '-^', 'LineWidth', 2.0, 'Color', app.RgbColor(i,:), ...
                    'MarkerSize', 7,  'HandleVisibility', 'off');

                % 绘制预测数据线
                x2 = opernum:1:opernum+1;
                y2 = [app.ObsData(end, i), app.PredictDis(i)];
                plot(x2, y2, '--^', 'LineWidth', 2.0, 'Color', app.RgbColor(i,:), ...
                    'MarkerSize', 7);
                text(0.1, 0.8,zhan(i),'Units', 'normalized','Color', app.RgbColor(i,:), ...
                    'FontSize',12, 'FontWeight','bold');

                set(gca, 'FontSize',12);
                grid off; axis square;
                pbaspect([1 1 1]);
                box on;
            end
        end

        % Button pushed function: OneDisButton
        function OneDisButtonPushed(app, event)
            % 获取待绘图测站
            selectedItem = app.PreDisDropDown.Value;  % 获取当前选中的文本
            itemsList = app.PreDisDropDown.Items;     % 获取所有选项的字符串数组
            index = find(strcmp(itemsList, selectedItem)); % 返回匹配项的索引

            % 清除目标坐标轴内容（根据HoldonCheckBox状态）
            if app.HoldonCheckBox.Value==0
                delete(app.SlopeUIAxes.Children);  % 直接删除所有图形对象
                cla(app.SlopeUIAxes, 'reset');
            end

            % 准备数据
            opernum = size(app.ObsData, 1);
            x = 1:1:opernum;
            y = app.ObsData(:, index)';

            % 在app.SlopeUIAxes中绘图
            hold(app.SlopeUIAxes, 'on');

            % 绘制观测数据线
            plot(app.SlopeUIAxes, x, y, '-^', 'LineWidth', 2.0, 'Color', app.RgbColor(index,:), ...
                'MarkerSize', 7, 'DisplayName', selectedItem, 'HandleVisibility', 'off');

            % 绘制预测数据线
            x2 = opernum:1:opernum+1;
            y2 = [app.ObsData(end, index), app.PredictDis(index)];
            plot(app.SlopeUIAxes, x2, y2, '--^', 'LineWidth', 2.0, 'Color', app.RgbColor(index,:), ...
                'MarkerSize', 7, 'DisplayName', selectedItem);

            % 设置坐标轴样式
            hold(app.SlopeUIAxes, 'off');
            legend(app.SlopeUIAxes, 'show', 'Location', 'best', 'Box', 'on');
            grid(app.SlopeUIAxes, 'on');
            box(app.SlopeUIAxes, 'on');

            % 设置标签和标题
            ylabel(app.SlopeUIAxes, '位移 (mm)');
            title(app.SlopeUIAxes, '预测位移');

            % 设置X轴刻度
            xticks(app.SlopeUIAxes, 1:1:opernum+2);
            xla = strcat('No.', string(x'));
            newest = '预测';
            xla = [string(xla'), string(newest)];

            xticklabels(app.SlopeUIAxes, xla);
            xlim(app.SlopeUIAxes, [0 opernum+2]);
        end

        % Menu selected function: SavePreMenu
        function SavePreMenuSelected(app, event)
            if isempty(app.PredictDis)
                uialert(app.SSIESUIFigure, '请先运行预测位移计算', '数据缺失', 'Icon', 'error');
                return;
            end
            % 列名编辑
            obsname=cell(1,size(app.ObsData,2)+1);
            obsname{1}='预测时期';
            obsname(1,2:end)=app.ObsName;
            % 表格内容
            % 表1预测位移
            b1=['No.',num2str(per)];
            da=[string(b1),app.PredictDis];
            % 让用户选择保存位置
            [filename, pathname] = uiputfile(...
                {'*.xlsx', 'Excel 文件 (*.xlsx)'}, ...
                '保存反演结果', ...
                'PredictDisplacement.xlsx'); % 默认文件名

            if isequal(filename, 0) || isequal(pathname, 0)
                uialert(app.SSIESUIFigure, '用户取消保存', '操作取消', 'Icon', 'info');
                return;
            end

            % 获取完整文件路径
            fullpath = fullfile(pathname, filename);
            [~, ~, ext] = fileparts(fullpath);

            try
                % 根据文件类型执行不同保存操作
                switch lower(ext)
                    case '.xlsx'
                        % 保存为Excel文件（带列名）
                        writetable(array2table(da, 'VariableNames', obsname), ...
                            fullpath, 'Sheet', '预测位移');
                    otherwise
                        error('不支持的文件格式');
                end

                % 显示成功消息
                successMsg = sprintf([...
                    '反演结果已成功保存至:\n%s\n\n'...
                    '包含以下数据:\n'...
                    '- 预测位移'], ...
                    fullpath);

                uialert(app.SSIESUIFigure, successMsg, '保存成功', 'Icon', 'success');

            catch ME
                % 显示错误消息
                errMsg = sprintf('保存失败:\n%s\n\n错误详情:\n%s', ...
                    fullpath, ME.message);
                uialert(app.SSIESUIFigure, errMsg, '保存错误', 'Icon', 'error');
            end
        end

        % Menu selected function: SaveProj
        function SaveProjMenuSelected(app, event)
            % 弹出文件保存对话框
            [filename, pathname] = uiputfile('*.mat', '保存工程文件');

            % 检查用户是否取消保存
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            % 组合完整文件路径
            fullpath = fullfile(pathname, filename);

            try
                % 创建用于保存数据的结构体
                savedData = struct();

                % 定义需要保存的属性白名单
                targetProperties = {
                    'InputData',...     % 训练模型所用输入参数数据
                    'OutputData',...    % 训练模型所用输出参数数据
                    'InParaDim', ...    % 模型输入参数维度
                    'OutParaDim',...    % 模型输出参数维度
                    'InputNorm',...     % 模型输入归一化
                    'OutputNorm',...    % 模型输出归一化
                    'Models',...        % 代理模型
                    'OutTest', ...      % 测试集
                    'OutTPre', ...      % 测试集预测值
                    'ObsName', ...      % 输出维度名字
                    'GeoPara', ...      % 岩土参数类别
                    'PriorValue', ...   % 岩土参数先验值
                    'ObsData',...       % 观测数据
                    'ObsError', ...     % 观测误差
                    'ExcaChara',...     % 开挖特征
                    'ResultPara',...    % 参数后验均值
                    'ResultStd', ...    % 参数后验标准差
                    'ResultCell',...    % 抽样元胞数组
                    'PredictDis',...    % 预测位移
                    'SlopeFoS' ...      % 边坡安全系数
                    };

                % 遍历白名单属性
                for i = 1:numel(targetProperties)
                    propName = targetProperties{i};

                    % 检查属性是否存在
                    if isprop(app, propName)
                        savedData.(propName) = app.(propName);
                    else
                        warning('属性 %s 不存在于应用程序中', propName);
                    end
                end

                % 添加保存时间戳
                savedData.SaveTimestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');

                % 保存到MAT文件（使用v7.3格式支持大数据）
                save(fullpath, '-struct', 'savedData');

                % 显示成功提示（使用现代提示框）
                uialert(app.SSIESUIFigure, '项目保存成功！', '保存状态', ...
                    'Icon', 'success', 'CloseFcn', @(h) delete(h));

            catch ME
                % 错误处理（显示详细错误）
                errordlg(sprintf('保存失败: %s (行号:%d)', ME.message, ME.stack(1).line), ...
                    '文件错误', 'modal');
            end
        end

        % Menu selected function: OpenProj
        function OpenProjMenuSelected(app, event)
            [filename, pathname] = uigetfile('*.mat', '加载工程文件');
            if isequal(filename,0), return; end

            try
                % 加载数据
                data = load(fullfile(pathname, filename));

                % 定义有效字段（排除系统字段）
                validFields = fieldnames(data);
                validFields = validFields(~strcmp(validFields, 'SaveTimestamp'));

                % 加载白名单属性
                for i = 1:numel(validFields)
                    field = validFields{i};
                    if isprop(app, field)
                        app.(field) = data.(field);
                    end
                end

                % 显示加载时间信息
                if isfield(data, 'SaveTimestamp')
                    msg = sprintf('成功加载 %s 保存的数据', data.SaveTimestamp);
                else
                    msg = '数据加载完成（无时间戳信息）';
                end
                uialert(app.SSIESUIFigure, msg, '加载状态', 'Icon','success');

            catch ME
                errordlg(sprintf('加载失败: %s\n错误发生在第%d行',...
                    ME.message, ME.stack(1).line), '加载错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSIESUIFigure = uifigure('Visible', 'off');
            app.SSIESUIFigure.Name = '边坡稳定性评估系统SSIES';
            app.SSIESUIFigure.CloseRequestFcn = createCallbackFcn(app, @SSIESUIFigureCloseRequest, true);
            app.MenuFile = uimenu(app.SSIESUIFigure);
            app.MenuFile.Tooltip = {''};
            app.MenuFile.Text = '文件';
            app.PriorDataImport = uimenu(app.MenuFile);
            app.PriorDataImport.MenuSelectedFcn = createCallbackFcn(app, @PriorDataImportMenuSelected, true);
            app.PriorDataImport.Tooltip = {'支持.txt、.dat、.csv、.xls、.xlsb、.xlsm、.xlsx、.xltm、.xltx格式，除表头外，第一行为均值，第二行为标准差'};
            app.PriorDataImport.Text = '参数先验数据导入';
            app.ObsDataImport = uimenu(app.MenuFile);
            app.ObsDataImport.MenuSelectedFcn = createCallbackFcn(app, @ObsDataImportMenuSelected, true);
            app.ObsDataImport.Tooltip = {'支持.txt、.dat、.csv、.xls、.xlsb、.xlsm、.xlsx、.xltm、.xltx格式，除表头外，每一行为一个时期的观测值'};
            app.ObsDataImport.Text = '观测数据导入';
            app.ObsErrorImport = uimenu(app.MenuFile);
            app.ObsErrorImport.MenuSelectedFcn = createCallbackFcn(app, @ObsErrorImportMenuSelected, true);
            app.ObsErrorImport.Tooltip = {'支持.txt、.dat、.csv、.xls、.xlsb、.xlsm、.xlsx、.xltm、.xltx格式，除表头外，第一行为误差均值，第二行为误差标准差'};
            app.ObsErrorImport.Text = '观测误差导入';
            app.ExcaChaImport = uimenu(app.MenuFile);
            app.ExcaChaImport.MenuSelectedFcn = createCallbackFcn(app, @ExcaChaImportSelected, true);
            app.ExcaChaImport.Tooltip = {'支持.txt、.dat、.csv、.xls、.xlsb、.xlsm、.xlsx、.xltm、.xltx格式，除表头外，应和观测数据时期数（行数）相等，否则导入失败'};
            app.ExcaChaImport.Text = '补充开挖特征';
            app.SaveProj = uimenu(app.MenuFile);
            app.SaveProj.MenuSelectedFcn = createCallbackFcn(app, @SaveProjMenuSelected, true);
            app.SaveProj.Separator = 'on';
            app.SaveProj.Text = '保存项目';
            app.OpenProj = uimenu(app.MenuFile);
            app.OpenProj.MenuSelectedFcn = createCallbackFcn(app, @OpenProjMenuSelected, true);
            app.OpenProj.Text = '打开项目';
            app.MenuSurrModel = uimenu(app.SSIESUIFigure);
            app.MenuSurrModel.Text = '代理模型';
            app.ModelInputIm = uimenu(app.MenuSurrModel);
            app.ModelInputIm.MenuSelectedFcn = createCallbackFcn(app, @ModelInputImMenuSelected, true);
            app.ModelInputIm.Text = '输入数据导入';
            app.ModelOutputIm = uimenu(app.MenuSurrModel);
            app.ModelOutputIm.MenuSelectedFcn = createCallbackFcn(app, @ModelOutputImMenuSelected, true);
            app.ModelOutputIm.Text = '响应数据导入';
            app.ModelTrain = uimenu(app.MenuSurrModel);
            app.ModelTrain.Separator = 'on';
            app.ModelTrain.Text = '训练模型';
            app.GPRMenu = uimenu(app.ModelTrain);
            app.GPRMenu.MenuSelectedFcn = createCallbackFcn(app, @GPRMenuSelected, true);
            app.GPRMenu.Text = 'GPR模型';
            app.BPNNMenu = uimenu(app.ModelTrain);
            app.BPNNMenu.MenuSelectedFcn = createCallbackFcn(app, @BPNNMenuSelected, true);
            app.BPNNMenu.Text = 'BPNN模型';
            app.MARSMenu = uimenu(app.ModelTrain);
            app.MARSMenu.MenuSelectedFcn = createCallbackFcn(app, @MARSMenuSelected, true);
            app.MARSMenu.Text = 'MARS模型';
            app.SaveModel = uimenu(app.MenuSurrModel);
            app.SaveModel.MenuSelectedFcn = createCallbackFcn(app, @SaveModelMenuSelected, true);
            app.SaveModel.Text = '保存模型';
            app.ImportModel = uimenu(app.MenuSurrModel);
            app.ImportModel.MenuSelectedFcn = createCallbackFcn(app, @ImportModelMenuSelected, true);
            app.ImportModel.Separator = 'on';
            app.ImportModel.Text = '导入模型';
            app.PabInverMenu = uimenu(app.SSIESUIFigure);
            app.PabInverMenu.Text = '概率反分析';
            app.BeginInver = uimenu(app.PabInverMenu);
            app.BeginInver.Text = '开始参数反演';
            app.MHMenu = uimenu(app.BeginInver);
            app.MHMenu.MenuSelectedFcn = createCallbackFcn(app, @MHMenuSelected, true);
            app.MHMenu.Text = 'MH方法';
            app.DREAMMenu = uimenu(app.BeginInver);
            app.DREAMMenu.MenuSelectedFcn = createCallbackFcn(app, @DREAMMenuSelected, true);
            app.DREAMMenu.Text = 'DREAM方法';
            app.SaveInverMenu = uimenu(app.PabInverMenu);
            app.SaveInverMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveInverMenuSelected, true);
            app.SaveInverMenu.Separator = 'on';
            app.SaveInverMenu.Text = '保存参数后验值';
            app.SlopeCondiMenu = uimenu(app.SSIESUIFigure);
            app.SlopeCondiMenu.Text = '边坡状态';
            app.PrediMenu = uimenu(app.SlopeCondiMenu);
            app.PrediMenu.MenuSelectedFcn = createCallbackFcn(app, @PrediMenuSelected, true);
            app.PrediMenu.Text = '下阶段位移预测';
            app.SavePreMenu = uimenu(app.SlopeCondiMenu);
            app.SavePreMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePreMenuSelected, true);
            app.SavePreMenu.Text = '保存预测结果';
            app.SimpleSlopeMenu = uimenu(app.SlopeCondiMenu);
            app.SimpleSlopeMenu.Separator = 'on';
            app.SimpleSlopeMenu.Text = '匀质土坡安全系数';
            app.FellenMenu = uimenu(app.SimpleSlopeMenu);
            app.FellenMenu.Text = '瑞典条分法';
            app.ModComMenu = uimenu(app.SimpleSlopeMenu);
            app.ModComMenu.Text = '代理模型计算';
            app.ComplexMenu = uimenu(app.SlopeCondiMenu);
            app.ComplexMenu.Text = '复杂边坡安全系数';
            app.ModelCompuMenu = uimenu(app.ComplexMenu);
            app.ModelCompuMenu.Text = '代理模型计算';
            app.SaveSafety = uimenu(app.SlopeCondiMenu);
            app.SaveSafety.Text = '保存安全系数';
            app.StopMenu = uimenu(app.SSIESUIFigure);
            app.StopMenu.Text = '退出';
            app.CancelNow = uimenu(app.StopMenu);
            app.CancelNow.MenuSelectedFcn = createCallbackFcn(app, @CancelNowMenuSelected, true);
            app.CancelNow.Text = '保存数据并退出';
            app.QuitApp = uimenu(app.StopMenu);
            app.QuitApp.MenuSelectedFcn = createCallbackFcn(app, @QuitAppMenuSelected, true);
            app.QuitApp.Text = '退出软件';
            app.TabTableData = uitabgroup(app.SSIESUIFigure);
            app.TabInverData = uitab(app.TabTableData);
            app.TabInverData.Title = '反演数据';
            app.PriorDataTable = uitable(app.TabInverData);
            app.PriorDataTable.ColumnName = {'参数 1'; '参数 2'; '参数 3'; '参数 4'; '...'};
            app.PriorDataTable.RowName = {};
            app.PriorDataTable.ColumnEditable = true;
            app.PriorDataTable.Tooltip = {'第一行为参数先验均值，第二行为参数先验标准差，支持手动修改'};
            app.PriorDataTable.Tag = '士大夫';
            app.Label = uilabel(app.TabInverData);
            app.Label.Text = '参数先验数据';
            app.ObsDataTable = uitable(app.TabInverData);
            app.ObsDataTable.ColumnName = {'数据1'; '数据2'; '数据3'; '数据4'; '...'};
            app.ObsDataTable.RowName = {};
            app.ObsDataTable.Tooltip = {'行号代表时期数'};
            app.Label_2 = uilabel(app.TabInverData);
            app.Label_2.Text = '观测数据';
            app.Label_4 = uilabel(app.TabInverData);
            app.Label_4.Text = '观测数据误差';
            app.ObsErrorTable = uitable(app.TabInverData);
            app.ObsErrorTable.ColumnName = {'数据1误差'; '数据2误差'; '数据3误差'; '数据4误差'; '...'};
            app.ObsErrorTable.RowName = {};
            app.ObsErrorTable.ColumnEditable = true;
            app.ObsErrorTable.Tooltip = {'第一行为误差均值，第二行为误差标准差，支持手动修改'};
            app.ObsErrorTable.Tag = '士大夫';
            app.Label_5 = uilabel(app.TabInverData);
            app.Label_5.Text = '开挖特征';
            app.ExcaChaTable = uitable(app.TabInverData);
            app.ExcaChaTable.ColumnName = {'特征1'; '特征2'; '特征3'; '特征4'; '...'};
            app.ExcaChaTable.RowName = {};
            app.ExcaChaTable.ColumnEditable = true;
            app.ExcaChaTable.Tooltip = {'行号代表开挖步骤，应和观测数据行数一致'};
            app.ExcaChaTable.Tag = '士大夫';
            app.TabModelVisual = uitab(app.TabTableData);
            app.TabModelVisual.Title = '代理模型可视化';
            app.UIAxesModel = uiaxes(app.TabModelVisual);
            title(app.UIAxesModel, '训练结果')
            xlabel(app.UIAxesModel, '数值模拟计算值 (mm)')
            ylabel(app.UIAxesModel, '模型预测值 (mm)')
            zlabel(app.UIAxesModel, 'Z')
            app.InputTable = uitable(app.TabModelVisual);
            app.InputTable.ColumnName = {'输入参数1'; '输入参数2'; '输入参数3'; '输入参数4'; '...'};
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.OutputTable = uitable(app.TabModelVisual);
            app.OutputTable.ColumnName = {'响应参数1'; '响应参数2'; '响应参数3'; '响应参数4'; '...'};
            app.OutputTable.RowName = {};
            app.MTLabelLeft = uilabel(app.TabModelVisual);
            app.MTLabelLeft.Text = '输入数据集';
            app.MTLabelRight = uilabel(app.TabModelVisual);
            app.MTLabelRight.Text = '输出数据集';
            app.ProgreTe = uitextarea(app.TabModelVisual);
            app.ProgreTe.Value = {'数据准备中...'};
            app.MoAccuTe = uitextarea(app.TabModelVisual);
            app.MoAccuTe.Value = {'模型精度：'; '决定系数R²：         RMSE:             最大误差：'};
            app.PaintModel = uipanel(app.TabModelVisual);
            app.PaintModel.Title = '可视化绘图';
            app.MDimPre = uibutton(app.PaintModel, 'push');
            app.MDimPre.ButtonPushedFcn = createCallbackFcn(app, @MDimPreButtonPushed, true);
            app.MDimPre.Text = '各维度训练结果';
            app.DimR2 = uibutton(app.PaintModel, 'push');
            app.DimR2.ButtonPushedFcn = createCallbackFcn(app, @DimR2Pushed, true);
            app.DimR2.Text = '各维度决定系数';
            app.DimRMSEButton = uibutton(app.PaintModel, 'push');
            app.DimRMSEButton.ButtonPushedFcn = createCallbackFcn(app, @DimRMSEButtonPushed, true);
            app.DimRMSEButton.Text = '各维度RMSE';
            app.MaxErrButton = uibutton(app.PaintModel, 'push');
            app.MaxErrButton.ButtonPushedFcn = createCallbackFcn(app, @MaxErrButtonPushed, true);
            app.MaxErrButton.Text = '各维度最大误差';
            app.TabParaInversion = uitab(app.TabTableData);
            app.TabParaInversion.Title = '参数反演可视化';
            app.PosterParaUIAxes = uiaxes(app.TabParaInversion);
            title(app.PosterParaUIAxes, '可视化')
            xlabel(app.PosterParaUIAxes, '参数')
            ylabel(app.PosterParaUIAxes, '后验')
            zlabel(app.PosterParaUIAxes, 'Z')
            app.PosterParaTable = uitable(app.TabParaInversion);
            app.PosterParaTable.ColumnName = {'更新阶段'; '参数1'; '参数2'; '参数3'; '参数4'; '...'};
            app.PosterParaTable.RowName = {};
            app.PosterLabel = uilabel(app.TabParaInversion);
            app.PosterLabel.Text = '后验参数表';
            app.Label_6 = uilabel(app.TabParaInversion);
            app.Label_6.Text = '选择表';
            app.ChoseTableDropDown = uidropdown(app.TabParaInversion);
            app.ChoseTableDropDown.Items = {'参数均值', '参数标准差', '变异系数'};
            app.ChoseTableDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoseTableDropDownValueChanged, true);
            app.ChoseTableDropDown.Value = '参数均值';
            app.Panel = uipanel(app.TabParaInversion);
            app.Panel.Title = '必要参数设置';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '代理模型';
            app.ModelDropDown = uidropdown(app.Panel);
            app.ModelDropDown.Items = {'GPR', 'BPNN', 'MARS'};
            app.ModelDropDown.Value = 'GPR';
            app.DropDown_3Label = uilabel(app.Panel);
            app.DropDown_3Label.Text = '参数先验分布';
            app.DistributionDropDown = uidropdown(app.Panel);
            app.DistributionDropDown.Items = {'对数正态分布', '正态分布'};
            app.DistributionDropDown.Value = '对数正态分布';
            app.InverTextArea = uitextarea(app.TabParaInversion);
            app.InverTextArea.Value = {'数据准备中...'};
            app.InverPaintPanel = uipanel(app.TabParaInversion);
            app.InverPaintPanel.Title = '坐标区绘图';
            app.Label_8 = uilabel(app.InverPaintPanel);
            app.Label_8.Text = '选择参数';
            app.PaintParaDropDown = uidropdown(app.InverPaintPanel);
            app.PaintParaDropDown.Items = {'请先进行反演计算', 'Option 2', 'Option 3', 'Option 4'};
            app.PaintParaDropDown.Value = '请先进行反演计算';
            app.Label_9 = uilabel(app.InverPaintPanel);
            app.Label_9.Text = '输入时期';
            app.ExcaEditField = uieditfield(app.InverPaintPanel, 'text');
            app.SamplingDiaButton = uibutton(app.InverPaintPanel, 'push');
            app.SamplingDiaButton.ButtonPushedFcn = createCallbackFcn(app, @SamplingDiaButtonPushed, true);
            app.SamplingDiaButton.Text = '抽样分布图';
            app.PDFDiaButton = uibutton(app.InverPaintPanel, 'push');
            app.PDFDiaButton.ButtonPushedFcn = createCallbackFcn(app, @PDFDiaButtonPushed, true);
            app.PDFDiaButton.Text = '概率密度曲线图';
            app.COVDiaButton = uibutton(app.InverPaintPanel, 'push');
            app.COVDiaButton.ButtonPushedFcn = createCallbackFcn(app, @COVDiaButtonPushed, true);
            app.COVDiaButton.Text = '变异系数变化图';
            app.Panel_2 = uipanel(app.TabParaInversion);
            app.Panel_2.Title = '独立窗口绘图';
            app.allCOVButton = uibutton(app.Panel_2, 'push');
            app.allCOVButton.ButtonPushedFcn = createCallbackFcn(app, @allCOVButtonPushed, true);
            app.allCOVButton.Text = '总体变异系数变化图';
            app.allPDFButton = uibutton(app.Panel_2, 'push');
            app.allPDFButton.ButtonPushedFcn = createCallbackFcn(app, @allPDFButtonPushed, true);
            app.allPDFButton.Text = '总体概率密度曲线图';
            app.SlopeTab = uitab(app.TabTableData);
            app.SlopeTab.Title = '边坡状态可视化';
            app.SlopeUIAxes = uiaxes(app.SlopeTab);
            title(app.SlopeUIAxes, '可视化')
            xlabel(app.SlopeUIAxes, 'X')
            ylabel(app.SlopeUIAxes, 'Y')
            zlabel(app.SlopeUIAxes, 'Z')
            app.SlopeUIAxes.ButtonDownFcn = createCallbackFcn(app, @SlopeUIAxesButtonDown, true);
            app.SlopeTable = uitable(app.SlopeTab);
            app.SlopeTable.ColumnName = {'预测位移'; '测站 1'; '测站 2'; '测站 3'; '测站 4'; '...'};
            app.SlopeTable.RowName = {};
            app.PreLabel = uilabel(app.SlopeTab);
            app.PreLabel.Text = '下阶段边坡施工预测位移';
            app.Label_10 = uilabel(app.SlopeTab);
            app.Label_10.Text = '选择表';
            app.DropDown = uidropdown(app.SlopeTab);
            app.DropDown.Items = {'预测位移', '安全系数'};
            app.DropDown.Value = '预测位移';
            app.DisplaDiaPanel = uipanel(app.SlopeTab);
            app.DisplaDiaPanel.Title = '预测位移可视化';
            app.Label_11 = uilabel(app.DisplaDiaPanel);
            app.Label_11.Text = '测站';
            app.PreDisDropDown = uidropdown(app.DisplaDiaPanel);
            app.HoldonCheckBox = uicheckbox(app.DisplaDiaPanel);
            app.HoldonCheckBox.Text = '保留坐标区图像';
            app.OneDisButton = uibutton(app.DisplaDiaPanel, 'push');
            app.OneDisButton.ButtonPushedFcn = createCallbackFcn(app, @OneDisButtonPushed, true);
            app.OneDisButton.Text = '单站位移变化图';
            app.AllDisButton = uibutton(app.DisplaDiaPanel, 'push');
            app.AllDisButton.ButtonPushedFcn = createCallbackFcn(app, @AllDisButtonPushed, true);
            app.AllDisButton.Text = '总体位移变化图';
            app.Panel_3 = uipanel(app.SlopeTab);
            app.Panel_3.Title = '边坡安全系数';
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '边坡级别';
            app.SlopeGradeDropDown = uidropdown(app.Panel_3);
            app.SlopeGradeDropDown.Items = {'1级', '2级', '3级', '4级', '5级'};
            app.SlopeGradeDropDown.Value = '1级';
            app.Label_13 = uilabel(app.Panel_3);
            app.Label_13.Text = '运用条件';
            app.SlopeCondiDropDown = uidropdown(app.Panel_3);
            app.SlopeCondiDropDown.Items = {'正常运用条件', '非正常运用条件Ⅰ', '非正常运用条件Ⅱ'};
            app.SlopeCondiDropDown.Value = '正常运用条件';
            app.Label_14 = uilabel(app.Panel_3);
            app.Label_14.Text = '边坡状态指示灯';
            app.SlopeSafeLamp = uilamp(app.Panel_3);
            app.SlopeSafeLabel = uilabel(app.Panel_3);
            app.SlopeSafeLabel.Text = '稳定';
            app.SlopeSafetyButton = uibutton(app.Panel_3, 'push');
            app.SlopeSafetyButton.Text = '边坡安全系数变化图';
            % Show the figure after all components are created
            app.SSIESUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlopeStabilityInversionEvaluationSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSIESUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSIESUIFigure)
        end
    end
end